classdef StahlDataAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StahlDataAnalysisUIFigure       matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        MainTab                         matlab.ui.container.Tab
        EngineClassDropDown             matlab.ui.control.DropDown
        SpecialboxCheckBox              matlab.ui.control.CheckBox
        CollapseFactorBetaEditField     matlab.ui.control.NumericEditField
        CollapseFactorBetaEditFieldLabel  matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        LoadFileButton                  matlab.ui.control.Button
        DatasetInputLabel               matlab.ui.control.Label
        CalculateCheckBox               matlab.ui.control.CheckBox
        CalculateCheckBox_2             matlab.ui.control.CheckBox
        TargetFuelmdotEditField         matlab.ui.control.NumericEditField
        TargetFuelmdotEditFieldLabel    matlab.ui.control.Label
        TargetLOXmdotEditField          matlab.ui.control.NumericEditField
        TargetLOXmdotEditFieldLabel     matlab.ui.control.Label
        CHPTTargetEditField             matlab.ui.control.NumericEditField
        CHPTTargetEditFieldLabel        matlab.ui.control.Label
        FIPTTargetEditField             matlab.ui.control.NumericEditField
        FIPTTargetEditFieldLabel        matlab.ui.control.Label
        OIPTTargetEditField             matlab.ui.control.NumericEditField
        OIPTTargetEditFieldLabel        matlab.ui.control.Label
        FTPTTargetEditField             matlab.ui.control.NumericEditField
        FTPTTargetEditFieldLabel        matlab.ui.control.Label
        OTPTTargetEditField             matlab.ui.control.NumericEditField
        OTPTTargetEditFieldLabel        matlab.ui.control.Label
        kgm3Label                       matlab.ui.control.Label
        LOXLN2densityEditField          matlab.ui.control.NumericEditField
        LOXLN2densityEditFieldLabel     matlab.ui.control.Label
        fmmultiplierTextArea            matlab.ui.control.NumericEditField
        Label_2                         matlab.ui.control.Label
        fmmultiplierTextAreaLabel       matlab.ui.control.Label
        OutputsLabel                    matlab.ui.control.Label
        LOXLeadEditField_3              matlab.ui.control.EditField
        LOXLeadEditField_3Label         matlab.ui.control.Label
        msLabel_3                       matlab.ui.control.Label
        FMVTimeEditField                matlab.ui.control.NumericEditField
        FMVTimeEditFieldLabel           matlab.ui.control.Label
        OMVTimeEditField                matlab.ui.control.NumericEditField
        OMVTimeEditFieldLabel           matlab.ui.control.Label
        LOXLeadEditField                matlab.ui.control.NumericEditField
        LOXLeadLabel                    matlab.ui.control.Label
        versionLabel                    matlab.ui.control.Label
        InputsLabel                     matlab.ui.control.Label
        madebyJacksonStahlLabel         matlab.ui.control.Label
        OrificeandinjectorusedinterchangablyinoutputsLabel  matlab.ui.control.Label
        TEditField_2                    matlab.ui.control.NumericEditField
        TLabel                          matlab.ui.control.Label
        TEditField                      matlab.ui.control.NumericEditField
        TEditFieldLabel                 matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        SamplingRateTextAreaLabel       matlab.ui.control.Label
        SamplingRateTextArea            matlab.ui.control.TextArea
        sLabel_3                        matlab.ui.control.Label
        fmvflowdurTextAreaLabel         matlab.ui.control.Label
        fmvflowdurTextArea              matlab.ui.control.TextArea
        sLabel_2                        matlab.ui.control.Label
        omvflowdurTextAreaLabel         matlab.ui.control.Label
        omvflowdurTextArea              matlab.ui.control.TextArea
        fuelsyscdaTextArea              matlab.ui.control.NumericEditField
        fuelorfcdaTextArea              matlab.ui.control.NumericEditField
        venturicdTextArea               matlab.ui.control.NumericEditField
        loxsyscdaTextArea               matlab.ui.control.NumericEditField
        loxorfcdaTextArea               matlab.ui.control.NumericEditField
        psiLabel                        matlab.ui.control.Label
        msLabel_2                       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        cm2Label_4                      matlab.ui.control.Label
        cm2Label_3                      matlab.ui.control.Label
        fuelsyscdaTextAreaLabel         matlab.ui.control.Label
        fuelorfcdaTextAreaLabel         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        cm2Label_2                      matlab.ui.control.Label
        cm2Label                        matlab.ui.control.Label
        venturicdTextAreaLabel          matlab.ui.control.Label
        loxsyscdaTextAreaLabel          matlab.ui.control.Label
        loxorfcdaTextAreaLabel          matlab.ui.control.Label
        PNPTTextAreaLabel               matlab.ui.control.Label
        PNPTTextArea                    matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        RunButton                       matlab.ui.control.Button
        datacsvEditFieldLabel           matlab.ui.control.Label
        FMVTimeTextAreaLabel            matlab.ui.control.Label
        datacsvEditField                matlab.ui.control.EditField
        WaterCheckBox                   matlab.ui.control.CheckBox
        JetACheckBox                    matlab.ui.control.CheckBox
        LN2CheckBox                     matlab.ui.control.CheckBox
        LOXCheckBox                     matlab.ui.control.CheckBox
        FMVTimeTextArea                 matlab.ui.control.TextArea
        OMVTimeTextArea                 matlab.ui.control.TextArea
        OMVTimeTextAreaLabel            matlab.ui.control.Label
        PlotsTab                        matlab.ui.container.Tab
        CEfficiencyButton               matlab.ui.control.Button
        SaveOpenButton                  matlab.ui.control.Button
        PRPTFCheckBox                   matlab.ui.control.CheckBox
        PRPTOCheckBox                   matlab.ui.control.CheckBox
        ShowdatatipsCheckBox_2          matlab.ui.control.CheckBox
        ShowdatatipsCheckBox            matlab.ui.control.CheckBox
        WithPressuresCheckBox           matlab.ui.control.CheckBox
        ODPCheckBox                     matlab.ui.control.CheckBox
        FLOWCheckBox                    matlab.ui.control.CheckBox
        PNPTCheckBox                    matlab.ui.control.CheckBox
        TemperaturesButton              matlab.ui.control.Button
        ShowinputsCheckBox              matlab.ui.control.CheckBox
        AvgFuelOrfCdAFlowMeterEditField  matlab.ui.control.EditField
        AvgFuelOrfCdAFlowMeterEditFieldLabel  matlab.ui.control.Label
        AvgFuelSysCdAFlowMeterEditField  matlab.ui.control.EditField
        AvgFuelSysCdAFlowMeterEditFieldLabel  matlab.ui.control.Label
        AvgFuelOrfCdASysCdAEditField    matlab.ui.control.EditField
        AvgFuelOrfCdASysCdAEditFieldLabel  matlab.ui.control.Label
        AvgFuelSysCdAOrificeEditField   matlab.ui.control.EditField
        AvgFuelSysCdAOrificeEditFieldLabel  matlab.ui.control.Label
        AvgLOXOrfCdAVenturiEditField    matlab.ui.control.EditField
        AvgLOXOrfCdAVenturiEditFieldLabel  matlab.ui.control.Label
        AvgLOXOrfCdASysCdAEditField     matlab.ui.control.EditField
        AvgLOXOrfCdASysCdAEditFieldLabel  matlab.ui.control.Label
        AvgLOXSysCdAVenturiEditField    matlab.ui.control.EditField
        AvgLOXSysCdAVenturiEditFieldLabel  matlab.ui.control.Label
        AvgLOXSysCdAOrificeEditField    matlab.ui.control.EditField
        AvgLOXSysCdAOrificeEditFieldLabel  matlab.ui.control.Label
        cm2Label_12                     matlab.ui.control.Label
        cm2Label_11                     matlab.ui.control.Label
        cm2Label_10                     matlab.ui.control.Label
        cm2Label_9                      matlab.ui.control.Label
        cm2Label_8                      matlab.ui.control.Label
        cm2Label_7                      matlab.ui.control.Label
        cm2Label_6                      matlab.ui.control.Label
        cm2Label_5                      matlab.ui.control.Label
        kgsLabel_6                      matlab.ui.control.Label
        avgfuelmdotfmTextAreaLabel      matlab.ui.control.Label
        avgfuelmdotfmTextArea           matlab.ui.control.TextArea
        kgsLabel_5                      matlab.ui.control.Label
        avgfuelmdotsysTextAreaLabel     matlab.ui.control.Label
        avgfuelmdotsysTextArea          matlab.ui.control.TextArea
        kgsLabel_4                      matlab.ui.control.Label
        avgfuelmdotorfTextAreaLabel     matlab.ui.control.Label
        avgfuelmdotorfTextArea          matlab.ui.control.TextArea
        kgsLabel_3                      matlab.ui.control.Label
        avgloxmdotventuriTextAreaLabel  matlab.ui.control.Label
        avgloxmdotventuriTextArea       matlab.ui.control.TextArea
        kgsLabel_2                      matlab.ui.control.Label
        avgloxmdotsysTextAreaLabel      matlab.ui.control.Label
        avgloxmdotsysTextArea           matlab.ui.control.TextArea
        kgsLabel                        matlab.ui.control.Label
        avgloxmdotorfTextAreaLabel      matlab.ui.control.Label
        avgloxmdotorfTextArea           matlab.ui.control.TextArea
        EngineDataButton                matlab.ui.control.Button
        CloseAllButton                  matlab.ui.control.Button
        ShowinputsCheckBox_2            matlab.ui.control.CheckBox
        FINPTCheckBox                   matlab.ui.control.CheckBox
        FLPTCheckBox                    matlab.ui.control.CheckBox
        CHPTCheckBox                    matlab.ui.control.CheckBox
        FIPTCheckBox                    matlab.ui.control.CheckBox
        FRUNPTCheckBox                  matlab.ui.control.CheckBox
        FTPTCheckBox                    matlab.ui.control.CheckBox
        OIPTCheckBox                    matlab.ui.control.CheckBox
        ORUNPTCheckBox                  matlab.ui.control.CheckBox
        OTPTCheckBox                    matlab.ui.control.CheckBox
        PRPT2CheckBox                   matlab.ui.control.CheckBox
        PRPT1CheckBox                   matlab.ui.control.CheckBox
        ThrustButton                    matlab.ui.control.Button
        ValvesButton                    matlab.ui.control.Button
        CdAsButton                      matlab.ui.control.Button
        mdotsButton                     matlab.ui.control.Button
        PressureLaddersButton           matlab.ui.control.Button
        PurgeButton                     matlab.ui.control.Button
        PlotAllButton                   matlab.ui.control.Button
        LoadInputsTab                   matlab.ui.container.Tab
        FMVTimeEditField_2              matlab.ui.control.NumericEditField
        FMVTimeEditField_2Label         matlab.ui.control.Label
        OMVTimeEditField_2              matlab.ui.control.NumericEditField
        OMVTimeEditField_2Label         matlab.ui.control.Label
        LOXLeadEditField_4              matlab.ui.control.NumericEditField
        LOXLeadEditField_4Label         matlab.ui.control.Label
        SwitchORUNandFRUNCheckBox       matlab.ui.control.CheckBox
        BepatientLabel                  matlab.ui.control.Label
        NotesLabel                      matlab.ui.control.Label
        TargetFuelmdotEditField_2       matlab.ui.control.NumericEditField
        TargetFuelmdotEditField_2Label  matlab.ui.control.Label
        TargetLOXmdotEditField_2        matlab.ui.control.NumericEditField
        TargetLOXmdotEditField_2Label   matlab.ui.control.Label
        CHPTTargetEditField_2           matlab.ui.control.NumericEditField
        CHPTTargetEditField_2Label      matlab.ui.control.Label
        FIPTTargetEditField_2           matlab.ui.control.NumericEditField
        FIPTTargetEditField_2Label      matlab.ui.control.Label
        OIPTTargetEditField_2           matlab.ui.control.NumericEditField
        OIPTTargetEditField_2Label      matlab.ui.control.Label
        FTPTTargetEditField_2           matlab.ui.control.NumericEditField
        FTPTTargetEditField_2Label      matlab.ui.control.Label
        OTPTTargetEditField_2           matlab.ui.control.NumericEditField
        OTPTTargetEditField_2Label      matlab.ui.control.Label
        DatasetDropDown                 matlab.ui.control.DropDown
        DatasetLabel                    matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        FuelSystemCdAEditField          matlab.ui.control.NumericEditField
        FuelSystemCdAEditFieldLabel     matlab.ui.control.Label
        FuelInjectorCdAEditField        matlab.ui.control.NumericEditField
        FuelInjectorCdAEditFieldLabel   matlab.ui.control.Label
        LOXSystemCdAEditField           matlab.ui.control.NumericEditField
        LOXSystemCdAEditFieldLabel      matlab.ui.control.Label
        LOXInjectorCdAEditField         matlab.ui.control.NumericEditField
        LOXInjectorCdAEditFieldLabel    matlab.ui.control.Label
        PowerPointBetaTab               matlab.ui.container.Tab
        slidecounterEditField           matlab.ui.control.EditField
        slidecounterEditFieldLabel      matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        MakePowerPointButton            matlab.ui.control.Button
        ChangelogTab                    matlab.ui.container.Tab
        FrankButton                     matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        times % Description
        data_name 
        file
        filepath
        lox             % Switch variable for what oxidizer is used on oxidizer side
        jeta            % Switch variable for what fuel is used on fuel side
        %sensors begin
        PRPT1           % Pressurant Gas Pressure Transducer - 1
        PRPT2           % Pressurant Gas Pressure Transducer - 2
        PRPTO           % Pressurant Gas Pressure Transducer - Oxidizer
        PRPTF           % Pressurant Gas Pressure Transducer - Fuel
        %fuel side
        FIPT            % Fuel Injector Pressure Transducer
        FLPT            % 
        FINPT           % Fuel
        FTPT            % Fuel Tank Pressure Transducer
        FMV             % Fuel Main Valve
        FRUNPT          % Fuel Run Line Pressure Transducer
        FBANG           % Fuel Bang Bang
        FLOW            % Fuel Flow Meter
        FTLC1           % Fuel Tank Load Cell 1
        FTLC2           % Fuel Tank Load Cell 2
        FTUTC           % Fuel Tank Ullage Thermocouple
        PUF             % Fuel Purge
        %lox side
        OIPT            % Oxidizer Injector Pressure Transducer
        OMV             % Oxidizer Main Valve
        OTPT            % Oxidizer Tank Pressure Transducer
        ORUNPT          % Oxidizer Run Line Pressure Transducer
        OBANG           % Oxidizer Bang Bang
        ODP             % Oxidizer Differential Pressure
        DPPT1
        DPPT2
        OTLC1           % Oxidizer Tank Load Cell 1
        OTLC2           % Oxidizer Tank Load Cell 2
        OFMTC           % Oxidizer Flow Meter (Venturi) Thermocouple
        OTBTC           % Oxidizer Tank Bottom Thermocouple
        OTTC1           % Oxidizer Tank Thermocouple 1
        OTTC2           % Oxidizer Tank Thermocouple 2
        OTTC3           % Oxidizer Tank Thermocouple 3
        OITC            % Oxidizer Injector Thermocouple
        PUO             % Oxidizer Purge
        %others
        PNPT            % Pneumatics Pressure Transducer
        PNPT2           % Pneumatics Pressure Transducer 2
        PUPT2           % Purge Pressure Transducer 2
        THRUST          % Thrust Structure Load Cell
        ISP             % Specific Impulse
        ISPavg_values   % Specific Impulse using averaged values
        CHPT            % Chamber Pressure Transducer
        ETC1            % Engine Thermocouple 1
        ETC2            % Engine Thermocouple 2
        ETC3            % Engine Thermocouple 3
        ETC4            % Engine Thermocouple 4
        HFTC1           % Heat Flux Thermocouple 1
        HFTC2           % Heat Flux Thermocouple 2
        IGNITER1        % Ingiter Voltage 1 (Nicrome & Epoxy)
        IGNITER2        % Igniter Voltage 2 (Solid Rocket Motor)
        SENSE           % Sense Wire (Voltage)
        %lox variables
        lox_mdot_cda               % Liquid Oxygen Mass Flow calculated with Injector
        lox_mdot_sys_cda           % Liquid Oxygen Mass Flow calculated with System CdA
        lox_mdot_venturi           % Liquid Oxygen Mass FLow calculated with Venturi Flow Meter
        avg_lox_mdot_orifice       % Average Value for the Liquid Oxygen Mass Flow calculated with Injector
        avg_lox_mdot_sys_cda       % Average Value for the Liquid Oxygen Mass Flow calculated with System CdA
        avg_lox_mdot_venturi       % Average Value for the Liquid Oxygen Mass Flow calculated with Venturi Flow Meter
        lox_sys_cda_orifice        % Liquid Oxygen System CdA calculated from Injector mdot
        lox_sys_cda_venturi        % Liquid Oxygen System CdA calculated from Venturi mdot
        avg_lox_sys_cda_orifice    % Average Value for the Liquid Oxygen System CdA calculated from Injector mdot
        avg_lox_sys_cda_venturi    % Average Value for the Liquid Oxygen System CdA calculated from Ventrui mdot
        lox_orf_cda_sys_cda        % Liquid Oxygen Orifice CdA calculated from the System mdot & Injector Pressure Drop
        lox_orf_cda_venturi        % Liquid Oxygen Orifice CdA calculated from the Venturi mdot & Injector Pressure Drop
        avg_lox_orf_cda_venturi    % Average Value for the Liquid Oxygen Injector CdA calculated from the Venturi mdot & Injector Pressure Drop
        avg_lox_orf_cda_sys_cda    % Average Value for the Liquid Oxygen Injector CdA calculated from the System mdot& Injector Pressure Drop
        %fuel variables
        fuel_mdot_cda              % Fuel mdot calculated from Injector CdA
        fuel_mdot_sys_cda          % Fuel mdot calculated from System CdA
        fuel_mdot_fm               % Fuel mdot calculated from Flow Meter CdA
        avg_fuel_mdot_orifice      % Average Value for the Fuel mdot calculated from Injector CdA
        avg_fuel_mdot_sys_cda      % Average Value for the Fuel mdot calculated from System CdA
        avg_fuel_mdot_fm           % Average Value for the Fuel mdot calculated from Flow Meter CdA
        fuel_sys_cda_orifice       % Fuel System CdA calculated from the Injector mdot
        fuel_sys_cda_fm            % Fuel System CdA calculated from the Flow Meter mdot
        avg_fuel_sys_cda_orifice   % Average Value for the Fuel System CdA calculated from the Injector mdot
        avg_fuel_sys_cda_fm        % Average Value for the Fuel System CdA calculated from the Flow Meter mdot
        fuel_orf_cda_sys_cda       % Fuel Orifice CdA calculated from the System mdot & Injector Pressure Drop
        fuel_orf_cda_fm            % Fuel Orifice CdA calculated from Flow Meter mdot & Injector Pressure Drop
        avg_fuel_orf_cda_sys_cda   % Average Value for the Fuel Injector CdA calculated from the System mdot & Injector Pressure Drop
        avg_fuel_orf_cda_fm        % Average Value for the Fuel Injector CdA calculated from the Flow Meter mdot & Injector Pressure Drop
        %inputs
        lox_orifice_cda            % Known or entered Liquid Oxygen Injector CdA
        lox_sys_cda                % Known or entered Liquid Oxygen System CdA
        venturi_cd                 % Known or entered Ventui Coefficient of Discharge  
        fuel_orifice_cda           % Known or entered Fuel Injector CdA
        fuel_sys_cda               % Known or entered Fuel System CdA           
        throat_area                % Nozzle Throat Area
        fm_multiplier
        %timing
        tzero
        tend
        %averages
        meanCHPT                   % Average Value of the Chamber Pressure Transducer
        meanOIPT                   % Average Value of the Oxidizer Injector Pressure Transducer
        meanFIPT                   % Average Value of the Fuel Injector Pressure Transducer

        avg_THRUST                 % Average Value of the Thrust Load Cell
        deltat                     % same as dt, time step.
    end
    
    methods (Access = public)
        
        function LoadNewData(app, data)                                      % Defines how GUI imports in Data from Hotfires/Static Fires
            app.TextArea.Value = "Processing (5/5)";
            pause(0.01); % This makes the Running box work

            if data(1:13)~=['Loaded file: ']                                 % Loaded file display
                app.data_name = data;
    
                if data(end-3:end)==['.mat']
                    load(data);
                elseif data(end-3:end)==['.csv']
                    data = readcell(data);
                else
                    app.TextArea.Value = "Error: Cannot read this filetype"; % if imported data is NOT in .mat or .csv, will not process.
                    pause(0.1);
                    return
                end
            else
                data = fullfile(app.filepath, app.file);
                app.data_name = app.file;
                if data(end-3:end)==['.mat']
                    load(data);
                elseif data(end-3:end)==['.csv']
                    data = readcell(data);
                else
                    app.TextArea.Value = "Error: Cannot read this filetype"; % if imported data is NOT in .mat or .csv, will not process.
                    pause(0.1);
                    return
                end
            end
            
            app.TextArea.Value = "Running (1/12)";
            pause(0.01); % This makes the Running box work

            %Inputs, all TextArea values, directly entered/loaded into the GUI
            % LOX
            app.lox_orifice_cda = app.loxorfcdaTextArea.Value;               % cm^2, inputted injector CDA lox side
            app.lox_sys_cda = app.loxsyscdaTextArea.Value;                   % cm^2, inputted system CDA lox sie
            app.venturi_cd = app.venturicdTextArea.Value;                    % inputted venturi coefficient of discharge lox side
            
            % Fuel
            app.fuel_orifice_cda = app.fuelorfcdaTextArea.Value;             % cm^2, inputted injector CDA fuel side
            app.fuel_sys_cda = app.fuelsyscdaTextArea.Value;                 % cm^2, inputted system CDA fuel side
            if app.CalculateCheckBox.Value==true
                if app.TargetFuelmdotEditField.Value < 0.83
                    app.fmmultiplierTextArea.Value = 0.5625 * app.TargetFuelmdotEditField.Value + 0.53;
                    pause(0.1);
                else
                    app.fmmultiplierTextArea.Value = 1.0;
                    pause(0.1);
                end
            end
            app.fm_multiplier = app.fmmultiplierTextArea.Value;
            
            % LOX Density, defines the density of the oxidizer based on what lox we use
            switch app.lox
                case 2
                    lox_density = app.LOXLN2densityEditField.Value % kg/m^3, add cool prop, (LOX)
                case 1
                    lox_density = app.LOXLN2densityEditField.Value % kg/m^3, (LN2)
            end
            
            % Fuel Density
            switch app.jeta
                case 2
                    fuel_density = 800 % kg/m^3, (Kerosene (Jet-A or RP-1))
                case 1
                    fuel_density = 1000 % kg/m^3, (Water)
            end
            
            % Engine throat area
            switch app.EngineClassDropDownLabel.Text       % On GUI display, the Engine Class dropdown under the import file line
                case 'Subscale - HETS'
                    app.throat_area = 0.001481663339628684; % m^2,   Subscale throat area
                otherwise
                    app.throat_area = 0.00390296138; % m^2,      Vespula throat area
            end
            ideal_c_star = 1650; % m/s
            
            app.TextArea.Value = "Running (2/12)";
            pause(0.01); % This makes the Running box work

            % Interval
            %t_before_omv = app.TEditField.Value;           % s, time before OMV opens to plot data
            t_start_avg = 1.5;                              % s, T+ time to pull average
            t_end_avg = 0.1;                                % s, time before OMV closes to pull average
            t_before_fmv = app.TEditField.Value;            % s, time before fmv opens to plot data
            t_plus = app.TEditField_2.Value;                % s, T+ time when to stop plotting
            
            %Index Sensors and Valves
            columns = size(data,2);
            time_index = 1;
            
            % Initialize sensors index to prevent error when sensor is missing, since these sensors are not ALWAYS mapped/cared about during testing.
            chpt_index = 1;
            ftlc2_index = 1;
            ftlc1_index = 1;
            otlc2_index = 1;
            otlc1_index = 1;
            otutc_index = 1;
            otbtc_index = 1;
            ofmtc_index = 1;
            ftutc_index = 1;
            dppt1_index = 1;
            dppt2_index = 1;
            thrust_index = 1;
            odp2_index = 1;
            etc1_index = 1;
            etc2_index = 1;
            etc3_index = 1;
            etc4_index = 1;
            hftc1_index = 1;
            hftc2_index = 1;
            pnpt2_index = 1;
            prpto_index = 1;
            prptf_index = 1;
            
            app.TextArea.Value = "Running (3/12)";
            pause(0.01); % This makes the Running box work

            % Add sensors and valves into for loop
            % Use below syntax to get the index (column location) of any recorded variable in the .csv/.mat file, such as FTPT
            % or FBANG_V, keep the i - 1 (first column is timestamp column)
            
            for i = 1:columns
                if strcmp(data{1,i}, ['FTPT'])  % if the text (string) in the first row reads 'FTPT' = is the column that has the data for FTPT:
                    ftpt_index = i-1;           % that column of FTPT is recorded but minus one for when the timestamp column is removed
                end
            
                if strcmp(data{1,i}, ['OTPT'])  % records the index for OTPT
                    otpt_index = i-1;
                end   
            
                if strcmp(data{1,i}, ['FMV'])   % records the index for FMV 
                    fmv_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OMV'])   % records the index for OMV
                    omv_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PUO'])   % records the index for PUO
                    puo_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PUF'])   % records the index for PUF
                    puf_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OIPT'])  % records the index for OIPT
                    oipt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FIPT'])  % records the index for FIPT
                    fipt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ORUNPT']) % records the index for ORUNPT
                    orunpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FRUNPT']) % records the index for FRUNPT
                    frunpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FBANG_V']) % records the index for voltage of FBANG
                    fbang_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OBANG_V']) % records the index for voltage of OBANG
                    obang_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PRPT1']) % records the index for PRPT1
                   prpt1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PRPT2']) % records the index for PRPT2
                   prpt2_index = i-1;
                end

                if strcmp(data{1,i}, ['PRPTO']) % records the index for PRPTO
                   prpto_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PRPTF']) % records the index for PRPTF
                   prptf_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PNPT']) % records the index for PNPT
                   pnpt_index = i-1;
                end
                
                if strcmp(data{1,i}, ['PNPT2']) % records the index for PNPT2
                   pnpt2_index = i-1;
                end

                if strcmp(data{1,i}, ['FLOW']) % records the index for FLOW
                   flow_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ODP']) % records the index for ODP
                   odp_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ODP2']) % records the index for ODP2
                   odp2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FTLC1']) % records the index for FTLC1
                   ftlc1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FTLC2']) % records the index for FTLC2
                   ftlc2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTLC1']) % records the index for OTLC1
                   otlc1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTLC2']) % records the index for OTLC2
                   otlc2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OFMTC']) % records the index for OFMTC
                   ofmtc_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTUTC']) % records the index for OTUTC
                   otutc_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTBTC']) % records the index for OTBTC
                   otbtc_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTTC1']) % records the index for OTTC1
                   ottc1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTTC2']) % records the index for OTTC2
                   ottc2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OTTC3']) % records the index for OTTC3
                   ottc3_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FTUTC']) % records the index for FTUTC
                   otbtc_index = i-1;
                end
            
                if strcmp(data{1,i}, ['DPPT1']) % records the index for DPPT1
                   dppt1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['DPPT2']) % records the index for DPPT2
                   dppt2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['Thrust']) % records the index for Thrust
                   thrust_index = i-1;
                end
            
                if strcmp(data{1,i}, ['CHPT']) % records the index for CHPT
                   chpt_index = i-1;
                end

                if strcmp(data{1,i}, ['FLPT']) % records the index for FLPT
                   flpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['FINPT']) % records the index for FINPT
                   finpt_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ETC1']) | strcmp(data{1,i}, ['ETC01']) % Engine Thermocouples could go recorded under either name
                   etc1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ETC2']) | strcmp(data{1,i}, ['ETC02']) % records the index for ETC2
                   etc2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ETC3']) | strcmp(data{1,i}, ['ETC03']) % records the index for ETC3
                   etc3_index = i-1;
                end

                if strcmp(data{1,i}, ['HFTC1']) % records the index for HFTC1
                   hftc1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['HFTC2']) % records the index for HFTC2
                   hftc2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['ETC4']) % records the index for ETC4
                  etc4_index = i-1;
                end
            
                if strcmp(data{1,i}, ['Ignitor1']) % records the index for Ignitor1
                  igniter1_index = i-1;
                end
            
                if strcmp(data{1,i}, ['Ignitor2']) % records the index for Ignitor2
                  igniter2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['PUPT2']) % records the index for PUPT2
                  pupt2_index = i-1;
                end
            
                if strcmp(data{1,i}, ['OITC']) % records the index for OITC
                  oitc_index = i-1;
                end
            
                if strcmp(data{1,i}, ['Sense']) % records the index for Sense wire
                  sense_index = i-1;
                end

            end
            
            app.TextArea.Value = "Running (4/12) ======="; % –
            pause(0.01); % This makes the Running box work

            % DO NOT TOUCH THIS PART, expect it to take a decent time to run
            % Separates time from other data because its easier to reduce that way
            
            app.times = [data{2:end,time_index}]-data{2,time_index}; % Sets time to start from data start instead of from unix time
            
            pure_data = data(2:end,2:end); % Separated data, removes the first column of times (we already documented it) and removes first row of sensor names (we already indexed the locations of the corresponding values)
            % !!! the importance of this is to make all the entries in pure_data numerical values in order to import them and graph later on

            app.TextArea.Value = "Running (4/12) +======";
            pause(0.01);

            %data formatting, -250 is a null value, consistent with AVI syntax
            pure_data(cellfun(@(x) isa(x, 'missing'), pure_data)) = {'-250.000000'}; % "missing" values for sensors are made -250 (traditional for AVI)
            app.TextArea.Value = "Running (4/12) ++=====";
            pause(0.01);

            pure_data(cellfun(@(x) strcmp(x, 'open'), pure_data)) = {'1.0000000000'}; % "open" states in pure_data are changed to a 1
            app.TextArea.Value = "Running (4/12) +++====";
            pause(0.01);

            pure_data(cellfun(@(x) strcmp(x, 'closed'), pure_data)) = {'0.0000000000'}; % "closed" states in pure_data are changed to a 0
            app.TextArea.Value = "Running (4/12) ++++===";
            pause(0.01);

            pure_data(cellfun(@(x) (length(x) < 5), pure_data)) = {'-0.00000000'}; % if the data ponit has less than 5 sig figs, replaced with 0
            app.TextArea.Value = "Running (4/12) +++++==";
            pause(0.01); 

            data2 = cellfun(@(x) x(1:5),pure_data,'UniformOutput',false);
            app.TextArea.Value = "Running (4/12) ++++++=";
            pause(0.01);

            data2 = str2double(data2); % all strings in data2 are now converted to doubles
          
            app.TextArea.Value = "Running (4/12) +++++++";
            pause(0.01);
            app.TextArea.Value = "Running (5/12)";
            pause(0.01); % This makes the Running box work

            %Intervals
            if app.jeta~=0
                local_time = [data2(1:end,fmv_index)]; % if the fuel side is flowing, local_time will be referenced from fmv (recall fmv is on 0/1 for closed/open)
            else
                local_time = [data2(1:end,omv_index)]; % if fuel side is not flowing, local_time will be referenced from omv (recall omv is on 0/1 for closed/open)
            end
            if app.CHPT~=0
                app.CHPT = [data2(1:end,chpt_index)]; % assigns CHPT using recorded CHPT index, have to do this before all other variable because of basing timings off of when CHPT is above ambient (combustion has started)
            elseif exist('flpt_index', 'var')==1
                app.CHPT = [data2(1:end,flpt_index)]; % if FLPT exists, it is referenced
            elseif app.lox~=0
                app.CHPT = [data2(1:end,oipt_index)];
            else
                app.CHPT = [data2(1:end,fipt_index)];
            end
            
            dt = (app.times(end)-app.times(1))/length(app.times);    % seconds, change in real time from one point to the next point
            app.deltat = dt;
            sampling_rate = 1/dt;                                    % Hz, how many data samples per second we get
            sampling_rate = round(sampling_rate, 3);                 % rounds to 3 decimal places
            app.SamplingRateTextArea.Value = num2str(sampling_rate); % converts sampling rate to a string and dispalys it on the GUI
            [~,data_start] = max(local_time);                        % records the index time of the max of local_time. Matlab itself will record the first instance of 1 if there are multiple instances. Essentially, data_start is recorded as soon as fmv opens.
            data_start = data_start - round(t_before_fmv/dt);        % adjusts data_start time to however long user wants to graph before FMV opens 
            
            data_end = data_start + round(t_before_fmv/dt) + round(t_plus/dt); % uses data_start and wanted to period to track when data should end (data_end)
            [~,t_zero] = max(app.CHPT>10);                                     % similar method as before, app.CHPT>10 takes all of app.CHPT and converts it to 1 for true (>10psi) and 0 for false (<10psi) and takes the first instance of the max(1=true->>10psi) as t_zero. Thus (t_zero) is the first instance where CHPT>10psi which is when combustion begins
            app.times = app.times - app.times(t_zero);                         % !!!!!re-adjusts app.times to reference t_zero as 0 in app.times
            if data_start < 1
                data_start = 1;
            end
            if data_end > length(data2)
                data_end = length(data2);                                      % in case data_end is longer than what the file has to plot, the file will stop plotting once it runs out of data.
            end
            data2 = data2(data_start:data_end, :);                             % !!!!!!!!!data2 is formatted from data_start to data_end
            app.times = app.times(data_start:data_end);                        % !!!!!!!!!gets times and formates it from data_start to data_end
            
            app.TextArea.Value = "Running (6/12)";
            pause(0.01); % This makes the Running box work

            %Variable Assignment
            % Pressurant
            app.PRPT1 = [data2(1:end,prpt1_index)]; % assigns PRPT1, pulls the whole column of data using its indexed location to pull the corresponding column to PRPT1 in original .csv/.mat file.
            app.PRPT2 = [data2(1:end,prpt2_index)]; % assigns PRPT2
            app.PRPTO = [data2(1:end,prpto_index)]; % assigns PRPT2
            app.PRPTF = [data2(1:end,prptf_index)]; % assigns PRPT2

            if app.jeta ~= 0                              % if fuel is flowing (water or kerosene)
                % Fuel
                app.FIPT = [data2(1:end,fipt_index)];     % assigns FIPT
                app.FTPT = [data2(1:end,ftpt_index)];     % assigns FTPT
                app.FMV = [data2(1:end,fmv_index)];       % assigns FMV
                app.FRUNPT = [data2(1:end,frunpt_index)]; % assigns FRUNPT
                app.FBANG = [data2(1:end,fbang_index)];   % assigns FBANG
                app.FLOW = [data2(1:end,flow_index)];     % assigns FLOW
                app.FTLC1 = [data2(1:end,ftlc1_index)];   % assigns FTLC1
                app.FTLC2 = [data2(1:end,ftlc2_index)];   % assigns FTLC2
                app.FTUTC = [data2(1:end,otutc_index)];   % assigns FTUTC
                app.PUF = [data2(1:end,puf_index)];       % assigns PUF
                if exist('flpt_index', 'var')==1
                    app.FLPT = [data2(1:end,flpt_index)]; % assigns FLPT
                    app.FLPT = app.FLPT-app.FLPT(1);      % tares FLPT
                else
                    app.FLPT = 0;
                end
                if exist('finpt_index', 'var')==1           % only if finpt_index exists
                    app.FINPT = [data2(1:end,finpt_index)]; % assigns FINPT
                    app.FINPT = app.FINPT-app.FINPT(1);     % tares FINPT
                else
                    app.FINPT = 0;
                end
                % Tare
                if app.FIPT(1)<30                         % if initial FIPT value is over 30psi, FIPT is re-tared
                app.FIPT = app.FIPT - app.FIPT(1);        % tares FIPT
                end
                if app.FRUNPT(1)<30                       % if initial FRUNPT value is over 30psi, FRUNPT is re-tared
                app.FRUNPT = app.FRUNPT - app.FRUNPT(1);  % tares FRUNPT
                end

                % CHPT
                app.CHPT = zeros(length(app.FIPT),1);     % CHPT becomes column of zeros the length of FIPT
            end
            
            if app.lox ~= 0 % if LOX side is flowing (LOX or LN2)
                % LOX
                app.OIPT = [data2(1:end,oipt_index)];     % assigns OIPT
                app.OMV = [data2(1:end,omv_index)];       % assigns OMV
                app.OTPT = [data2(1:end,otpt_index)];     % assigns OTPT
                app.ORUNPT = [data2(1:end,orunpt_index)]; % assigns ORUNPT
                app.OBANG = [data2(1:end,obang_index)];   % assigns OBANG
                app.ODP = [data2(1:end,odp_index)];       % assigns ODP
                %app.ODP2 = [data2(1:end,odp2_index)];
                app.DPPT1 = [data2(1:end,dppt1_index)];   % assigns DPPT1
                app.DPPT2 = [data2(1:end,dppt2_index)];   % assigns DPPT2
                app.OTLC1 = [data2(1:end,otlc1_index)];   % assigns OTLC1
                app.OTLC2 = [data2(1:end,otlc2_index)];   % assigns OTLC2
                app.OFMTC = [data2(1:end,ofmtc_index)];   % assigns OFMTC
                %app.OTUTC = [data2(1:end,otutc_index)];
                app.OTBTC = [data2(1:end,otbtc_index)];   % assigns OTBTC
                app.OTTC1 = [data2(1:end,ottc1_index)];   % assigns OTTC1
                app.OTTC2 = [data2(1:end,ottc2_index)];   % assigns OTTC2
                app.OTTC3 = [data2(1:end,ottc3_index)];   % assigns OTTC3
                app.OITC = [data2(1:end,oitc_index)];     % assigns OITC
                app.PUO = [data2(1:end,puo_index)];       % assigns PUO
                % Tare
                if app.OIPT(1)<30                         % if initial OIPT value is over 30psi, OIPT is re-tared
                app.OIPT = app.OIPT-app.OIPT(1);          % tares OIPT from initial value
                end
                if app.ORUNPT(1)<30                       % if initial ORUNPT value is over 30psi, ORUNPT is re-tared
                app.ORUNPT = app.ORUNPT-app.ORUNPT(1);    % tares ORUNPT from initial value
                end

                % CHPT
                app.CHPT = zeros(length(app.OIPT),1);     % CHPT becomes column of zeros the length of OIPT
            end
            
            % Pneumatics
            app.PNPT = [data2(1:end,pnpt_index)];   % assigns PNPT
            app.PNPT2 = [data2(1:end,pnpt2_index)]; % assigns PNPT2
            
            % Purge
            app.PUPT2 = [data2(1:end,pupt2_index)]; % assigns PUPT2
            
            if app.jeta==2 && app.lox==2 % if we are hotfiring with KeroLOX
                % Engine
                app.THRUST = [data2(1:end,thrust_index)];      % assigns THRUST
                app.CHPT = [data2(1:end,chpt_index)];          % assigns CHPT
                app.ETC1 = [data2(1:end,etc1_index)];          % assigns ETC1
                %app.ETC2 = [data2(1:end,etc2_index)];         % assigns ETC2
                app.ETC3 = [data2(1:end,etc3_index)];          % assigns ETC3
                %app.ETC4 = [data2(1:end,etc4_index)];         % assigns ETC4
                app.HFTC1 = [data2(1:end, hftc1_index)];       % assigns HFTC1
                app.HFTC2 = [data2(1:end, hftc2_index)];       % assigns HFTC2
                app.IGNITER1 = [data2(1:end,igniter1_index)];  % assigns Ignitor 1
                app.IGNITER2 = [data2(1:end,igniter2_index)];  % assigns Ignitor 2
                app.SENSE = [data2(1:end,sense_index)];        % assigns Sense wire
                %Tare
                if app.CHPT(1)<30                              % if CHPT initial reading is over 30psi, CHPT is re-tared
                    app.CHPT = app.CHPT-app.CHPT(1);           % tares CHPT from initial value
                end
            end
            
            app.TextArea.Value = "Running (7/12)";
            pause(0.01); % This makes the Running box work

            if app.CalculateCheckBox_2.Value==true
                %LOX/LN2 Density 
                %the following calculations come from DENSITIES OF SATURATED
                %LIQUID OXYGEN AND NITROGEN, by K. GOLDMAN and N. G.
                %SCRASE, 1968. The expermiment determined a relationship
                %between Temp and density. The value of pressure is a function 
                % of temperature at saturation, so we may determine density 
                % with a single variable.
                switch app.lox
                    case 2 %LOX option is checked, not LN2
                        x = (1 - app.OTTC3(1)/154.77)^(1/3); %Input for regression Function, effectively normalizes the input
                        %154.77 is the provided critical temp of lox, in K
                        %values for coeffs of regression:
                        a = 0.487609;
                        b = -0.203117;
                        c = 4.624474;
                        d = -9.374991;
                        e = 9.517905;
                        f = -3.590726;
                        p = a + b*x + c*x^2 + d*x^3 + e*x^4 + f*x^5; % Regression function for LOX per paper cited above
                        lox_density = p*1000;
                        if isreal(p)
                            app.LOXLN2densityEditField.Value = p*1000;
                        else
                            app.TextArea.Value = "Error: LOX is not present in this dataset.";
                            pause(0.01); % This makes the Running box work
                            return
                        end
                        pause(0.1);
                    case 1 %LN2 option is checked, not LOX
                        x = (1 - app.OTTC3(1)/126.26)^(1/3); %Input for regression Function, effectively normalizes the input
                        %126.26 is the provided critical temp of lox, in K
                        %values for coeffs of regression:
                        a = 0.294536009;
                        b = 0.65818693;
                        c = -0.373977236;
                        d = 0.665186539;
                        e = 0.167862430;
                        f = 1.32508915;
                        g = -4.81545606;
                        h = 3.41622168;
                        p = a + b*x + c*x^2 + d*x^3 + e*x^4 + f*x^5 + g*x^6 + h*x^7;  % Regression function for LN2 per paper cited above
                        lox_density = p*1000;
                        if isreal(p)
                            app.LOXLN2densityEditField.Value = p*1000;
                        else
                            app.TextArea.Value = "Error: LN2 is not present in this dataset.";
                            pause(0.01); % This makes the Running box work
                            return
                        end
                        pause(0.1);
                end
            end

            app.TextArea.Value = "Running (8/12)";
            pause(0.01); % This makes the Running box work

            %init end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            % %Raw Plots
            % % Tank Load Cells
            % dOTLC1 = (OTLC1(1) - OTLC1(end))/2.205; % kg
            % dOTLC2 = (OTLC2(1) - OTLC2(end))/2.205; % kg
            % dOTLC = dOTLC1 + dOTLC2;
            % dFTLC1 = (FTLC1(1) - FTLC1(end))/2.205; % kg
            % dFTLC2 = (FTLC2(1) - FTLC2(end))/2.205; % kg
            % dFTLC = dFTLC1 + dFTLC2;
            % figure
            % hold on
            % yyaxis left
            % plot(app.times, OTLC1/2.205, 'b-')
            % plot(app.times, OTLC2/2.205, 'b-')
            % plot(app.times, FTLC1/2.205, 'r-')
            % plot(app.times, FTLC2/2.205, 'r-')
            % title('Tank Load Cells')
            % subtitle(data_name, 'Interpreter', 'none')
            % ylabel('Mass (kg)')
            % xlabel('Time (s)')
            % yyaxis right
            % plot(app.times, zeros(length(app.times))+dOTLC, 'c-')
            % plot(app.times, zeros(length(app.times))+dFTLC, 'm-')
            % legend('OTLC1', 'OTLC2', 'FTLC1', 'FTLC2', 'dOTLC', 'dFTLC')
            % hold off        
            
            %Data Reduction
            % Valve Timings
            if app.lox~=0  % if oxidizer is flowing
                OIPT_mask = app.OIPT > 10;                                % OIPT column of pressure readings is masked into 0/1 on if that value is above 10psi (1) or below 10psi (0)
                [~,i_OMV] = max(app.OMV);                                 % Matlab finds the indexed time (i) where the max value of 1 (open) occurs with OMV. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where OMV is open
                [~,i_OIPT] = max(OIPT_mask);                              % Matlab finds the indexed time (i) where the max value of 1 occurs on the OIPT mask. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where OIPT>10psi
                OMV_open_time = app.times(i_OIPT)-app.times(i_OMV);       % This is the amount of time that OMV is open where oxidizer is travelling to the injector.
                OMV_open_time = round(OMV_open_time, 3);                  % round OMV_open_time to 3 decimal places
                app.OMVTimeTextArea.Value = num2str(OMV_open_time*1000);  % OMV_open_time is in milliseconds, converts it to seconds and then into a string format for display on the GUI
            end
            if app.jeta~=0  % if fuel is flowing
                FIPT_mask = app.FIPT > 10;                                % FIPT column of pressure readings is masked into 0/1 on if that value is above 10psi (1) or below 10psi (0)
                [~,i_FMV] = max(app.FMV);                                 % Matlab finds the indexed time (i) where the max value of 1 (open) occurs with FMV. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where FMV is open
                [~,i_FIPT] = max(FIPT_mask);                              % Matlab finds the indexed time (i) where the max value of 1 occurs on the FIPT mask. If multiples instances of the max occurs, matlab will return the first instance. Hence, the indexed time is the first time where FIPT>10psi
                FMV_open_time = app.times(i_FIPT)-app.times(i_FMV);       % This is the amount of time that FMV is open where fuel is travelling to the injector.
                FMV_open_time = round(FMV_open_time, 3);                  % round FMV_open_time to 3 decimal places
                app.FMVTimeTextArea.Value = num2str(FMV_open_time*1000);  % FMV_open_time is in milliseconds, converts it to seconds and then into a string format for display on the GUI
            end
            if (app.jeta~=0 && app.lox~=0)  % if both sides are flowing
                loxaccount = app.LOXLeadEditField.Value-(app.FMVTimeEditField.Value-app.OMVTimeEditField.Value);  % There is a deviation from desired Lox Lead in the time it takes for the FMV & OMV to actuate and fluid to travel to their respective injector PTs>10psi
                app.LOXLeadEditField_3.Value = num2str(1000*(FMV_open_time-OMV_open_time)+loxaccount);            % Actual Lox lead is calculated from the lox account and the difference is open times, then it is multiplied to get it in milliseconds and converted into a string to display on the GUI
            end
            
            % Average Mask
            % Mask for Interval where Average is Taken
            if app.jeta~=0
                FMV_mask = app.FMV > 0.5;          % when FMV is open
            else
                FMV_mask = app.OMV > 0.5;          % if FMV is never open or unrreadable, goes to when OMV is open
            end
            if app.CHPT~=0                         % if CHPT is being recorded essentially
                CHPT_mask = app.CHPT > 10;         % CHPT is masked to 0 (<10psi) and 1 (>10psi) for values existing in CHPT
            elseif exist('flpt_index', 'var')==1  
                CHPT_mask = app.FLPT > 10;         % if CHPT isn't being recorded but FLPT exists, CHPT is masked onto FLPT>10psi
            elseif app.lox~=0
                CHPT_mask = app.OIPT > 10;         % if neither work but lox side is flowing, CHPT is masked onto OIPT>10psi
            else                                    
                CHPT_mask = app.FIPT > 10;         % If none of the above work, CHPT is masked to FIPT>10psi
            end
            [~, i_start] = max(CHPT_mask);         % pulls the indexed start time, or the first instance where CHPT_mask hits its maximum (1), which indicates the first time CHPT or masked equivalent > 10psi
            if app.jeta~=0                         % if fuel side is flowing
                [~, i_shift] = max(app.FMV);       % do not need to index FMV or OMV since they are both recorded as valves states (1 & 0 for open & closed). i_shift is recorded as the first time FMV is commanded open
            else
                [~, i_shift] = max(app.OMV);       % if fuel is not flowing, i_shift is recorded as the first time OMV is commanded open
            end
            [~, i_end] = max(app.times(FMV_mask)); % i_end is recorded as the indexed time where FMV_mask is one where FMV will be commanded closed once the fire has ended)
            i_end = i_end + i_shift;               % readjusts the indexed end time to account for the time where FMV was not open
            i = false(length(app.times));          % creates a matrix that has the length and width of the quantity of values recorded for time
            i_start = i_start + round(t_start_avg/dt); % i_start, where FMV opens, and adds the inputted desired time to track before t=0
            i_end = i_end - round(t_end_avg/dt);   % i_end is readjusted to add the time after end of hotfire (after shut off) that vales still need to be recorded (t_end_avg/dt rounded to nearest interget)
            i(i_start:i_end) = true;               % !!!!!!!!!!!!!I actually don't know lol

            app.tzero = find(app.times>0);        
            app.tend = find(app.times>5);          
            if isempty(app.tzero)
                app.TextArea.Value = "Error: Incorrect input fluids for dataset.";
                pause(0.01); % This makes the Running box work
                return
            end
            app.tzero=app.tzero(1);
            app.tend=app.tend(1);
            
            app.TextArea.Value = "Running (9/12)";
            pause(0.01); % This makes the Running box work

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % % % % %LOX Side
            if app.lox~=0
                % LOX Mdot from Orifice CdA
                app.lox_mdot_cda = app.lox_orifice_cda./100^2.*sqrt(2.*lox_density.*abs(app.OIPT-app.CHPT).*6895); % kg/s, uses known CdA and pressure drop across the injector (OIPT & CHPT)
    
                % LOX Mdot from System CdA
                app.lox_mdot_sys_cda = app.lox_sys_cda./100^2.*sqrt(2.*lox_density.*abs(app.OTPT-app.OIPT).*6895); % kg/s, uses known CdA and pressure drop across the system (OTPT to OIPT)
    
                % LOX Flow Duration
                OMV_mask = app.OMV > 0.5;                                                 % interval masked when OMV is open (OMV is 1 for open > 0.5)
                omv_flow_duration = max(app.times(OMV_mask)) - min(app.times(OMV_mask));  % calculates the time that OMV is open from first to last instance
                app.omvflowdurTextArea.Value = num2str(omv_flow_duration);                % convert to string then displays on GUI
    
                % LOX Venturi
                inlet_ID = 0.844; % in
                throat_ID = 0.4375; % in
                app.lox_mdot_venturi = app.venturi_cd*pi*(inlet_ID/39.37/2)^2*sqrt(2*lox_density*abs(app.ODP)*6895/(((pi*(inlet_ID/39.37/2)^2)/(pi*(throat_ID/39.37/2)^2))^2-1)); % kg/s, Bernoulli's Principle
    
                % Average Mdots
                app.avg_lox_mdot_orifice = mean(app.lox_mdot_cda(i));     % kg/s, averaged over all values of i
                app.avg_lox_mdot_sys_cda = mean(app.lox_mdot_sys_cda(i)); % kg/s, averaged over all values of i
                app.avg_lox_mdot_venturi = mean(app.lox_mdot_venturi(i)); % kg/s, averaged over all values of i

                app.avgloxmdotorfTextArea.Value = num2str(app.avg_lox_mdot_orifice); % converts all to strings and displays them on the GUI
                app.avgloxmdotsysTextArea.Value = num2str(app.avg_lox_mdot_sys_cda);
                app.avgloxmdotventuriTextArea.Value = num2str(app.avg_lox_mdot_venturi);
                %
                % System CdA
                app.lox_sys_cda_venturi = app.lox_mdot_venturi./sqrt(2*lox_density*abs((app.OTPT-app.OIPT)+.1)*6895)*100^2; % cm^2, calculated System CdA using the Mdot from the venturi and the pressure drop across the system
                app.lox_sys_cda_orifice = app.lox_mdot_cda./sqrt(2*lox_density*abs((app.OTPT-app.OIPT)+.1)*6895)*100^2; % cm^2, calculated System CdA using the Mdot from the injector and the pressure drop across the system
    
                % Average System CdA
                app.avg_lox_sys_cda_orifice = mean(app.lox_sys_cda_orifice(i)); % cm^2, averaged over all values of i
                app.avg_lox_sys_cda_venturi = mean(app.lox_sys_cda_venturi(i)); % cm^2, averaged over all values of i
    
                %avg_lox_sys_cda_lc = mean(lox_sys_cda_lc(i)) % cm^2
                
                % Injector CdA (Verifying Injector CdA with other mdot measurements)
                app.lox_orf_cda_venturi = app.lox_mdot_venturi./sqrt(2*lox_density*abs(app.OIPT-app.CHPT)*6895)*100^2; % cm^2, uses Mdot calculated from venturi CdA instead of Mdot from Injector CdA
                app.lox_orf_cda_sys_cda = app.lox_mdot_sys_cda./sqrt(2*lox_density*abs(app.OIPT-app.CHPT)*6895)*100^2; % cm^2, uses Mdot calculated from system CdA instead of Mdot from Injector CdA
                app.avg_lox_orf_cda_venturi = mean(app.lox_orf_cda_venturi(i)); % cm^2, averaged over all values of i
                app.avg_lox_orf_cda_sys_cda = mean(app.lox_orf_cda_sys_cda(i)); % cm^2, averaged over all values of i

                app.AvgLOXSysCdAOrificeEditField.Value = num2str(app.avg_lox_sys_cda_orifice);   % converts all to strings and displays them on the GUI
                app.AvgLOXSysCdAVenturiEditField.Value = num2str(app.avg_lox_sys_cda_venturi);
                app.AvgLOXOrfCdASysCdAEditField.Value = num2str(app.avg_lox_orf_cda_venturi);
                app.AvgLOXOrfCdAVenturiEditField.Value = num2str(app.avg_lox_orf_cda_sys_cda);
                % % 
            end
            
            app.TextArea.Value = "Running (10/12)";
            pause(0.01); % This makes the Running box work

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            % % Fuel Side
            if app.jeta~=0 % if a fluid is flowing on the fuel side, app.jeta~=0 (jeta does not equal 0)
                % Mdot from Orifce CdA
                app.fuel_mdot_cda = app.fuel_orifice_cda./100^2.*sqrt(2.*fuel_density.*abs(app.FIPT-app.CHPT).*6895); % kg/s, uses known CdA and pressure drop across the injector (FIPT & CHPT)
    
                % Mdot from Orifce CdA
                app.fuel_mdot_sys_cda = app.fuel_sys_cda./100^2.*sqrt(2.*fuel_density.*abs(app.FTPT-app.FIPT).*6895); % kg/s, uses known CdA and pressure drop across the system (FTPT & FIPT)
                
                % Fuel Flow Meter
                app.fuel_mdot_fm = app.FLOW.*fuel_density/1000;  % kg/s, pulls values from app.FLOW and converts it to
                app.fuel_mdot_fm = app.fuel_mdot_fm .* app.fm_multiplier; % kg/s, multiplies the documented fuel_mdot_fm by the flow meter multiplier to get true fuel_mdot_fm
                fmavg(app.fuel_mdot_fm>0.2) = true; % only takes into account values above 0.2 kg/s for averaging into fmavg
                if ~any(fmavg) % if no values above 0.2 kg/s exist than fmavg errors out
                    app.TextArea.Value = "Error: Fuel is not present in this dataset.";
                    pause(0.01);
                    return
                end
                %[~, j_first] = max(j);
                %[~, i_first] = max(i);
                %fm_lag = j_first-i_first;
                %j_last = j_first + (length(i) - fm_lag);
                %j(j_last:end) = false;
                fmavg(1:round(end/2))=false;
                fmavg(round((2/3)*end):end)=false;
                
                % Fuel Flow Duration
                FMV_mask = app.FMV > 0.5;                                                    % interval masked when OMV is open (OMV is 1 for open > 0.5)
                fmv_flow_duration = max(app.times(FMV_mask)) - min(app.times(FMV_mask));     % calculates the time that OMV is open from first to last instance
                app.fmvflowdurTextArea.Value = num2str(fmv_flow_duration);                   % convert to string then displays on GUI
    
                % Average Fuel Mdots
                app.avg_fuel_mdot_orifice = mean(app.fuel_mdot_cda(i));     % kg/s, averaged over all values in i
                app.avg_fuel_mdot_sys_cda = mean(app.fuel_mdot_sys_cda(i)); % kg/s, averaged over all values in i
                app.avg_fuel_mdot_fm = mean(app.fuel_mdot_fm(fmavg));       % kg/s, averaged over all values in i

                app.avgfuelmdotorfTextArea.Value = num2str(app.avg_fuel_mdot_orifice); % converts values into strings and displays on the GUI
                app.avgfuelmdotsysTextArea.Value = num2str(app.avg_fuel_mdot_sys_cda);
                app.avgfuelmdotfmTextArea.Value = num2str(app.avg_fuel_mdot_fm);

                % System CdA
                app.fuel_sys_cda_fm = app.fuel_mdot_fm./sqrt(2.*fuel_density.*abs((app.FTPT-app.FIPT)+0.1)*6895).*100^2; % cm^2, calculates System CdA using Mdot from the Fuel Turbine Flow Meter and dP across the System
                app.fuel_sys_cda_orifice = app.fuel_mdot_cda./sqrt(2.*fuel_density.*abs((app.FTPT-app.FIPT)+0.1)*6895).*100^2; % cm^2, calculates System CdA using Mdot from the Injector and dP across the System

                % Average System CdA
                app.avg_fuel_sys_cda_orifice = mean(app.fuel_sys_cda_orifice(i)); % cm^2, averaged over all values in i
                app.avg_fuel_sys_cda_fm = mean(app.fuel_sys_cda_fm(fmavg)); % cm^2, averaged over all values in i
    
                % Injector CdA (Verifying Injector CdA with other mdot measurements)
                app.fuel_orf_cda_fm = app.fuel_mdot_fm./sqrt(2*fuel_density*abs(app.FIPT-app.CHPT)*6895)*100^2; % cm^2, calculates Injector CdA with Mdot from fuel flow meter and dP across Injector
                app.fuel_orf_cda_sys_cda = app.fuel_mdot_sys_cda./sqrt(2*fuel_density*abs(app.FIPT-app.CHPT)*6895)*100^2; % cm^2, calculates Injector CdA with Mdot from the System using System CdA and dP across Injector
                app.avg_fuel_orf_cda_fm = mean(app.fuel_orf_cda_fm(fmavg));       % cm^2, averaged over all values in fmavg
                app.avg_fuel_orf_cda_sys_cda = mean(app.fuel_orf_cda_sys_cda(i)); % cm^2, averaged over all values in i

                app.AvgFuelSysCdAOrificeEditField.Value = num2str(app.avg_fuel_sys_cda_orifice);  % converts values into strings and displays on the GUI
                app.AvgFuelSysCdAFlowMeterEditField.Value = num2str(app.avg_fuel_sys_cda_fm);
                app.AvgFuelOrfCdASysCdAEditField.Value = num2str(app.avg_fuel_orf_cda_sys_cda);
                app.AvgFuelOrfCdAFlowMeterEditField.Value = num2str(app.avg_fuel_orf_cda_fm);
                % % 
            end

            app.TextArea.Value = "Running (11/12)";
            pause(0.01); % This makes the Running box work
            % 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            if app.CheckBox.Value && app.lox==2
                %collapse should be an array with valves of lox collapse at given times.
                %when main valve is closed it should be zero. we can change this later
                % init=i_OMV
                % end=i_OMV+OMV_open_time
                gamma = 1.4;
                A = 7.67e-4; %m2 %0.021; %orifice area, in^2 or something. THIS MAY CAUSE A UNIT ERROR.
                cd =0.7; % for orifice. I forget why this is in my notes. should verify.
                R = 259;  %verify this later. J/kg K
                T = 298; %temp, K

                [~,f_OMV] = min(app.OMV(i_OMV:end));
                P = app.PRPT2(i_OMV:f_OMV-1); %pressures
                obangvec = app.OBANG(i_OMV:f_OMV-1) > 1;
                len = f_OMV-i_OMV;

                mdot = app.lox_mdot_venturi(i_OMV:f_OMV-1);
                
                %we assume T and P to be arrays of the same length, and values at the same
                %index to correspond to the same time. If one is slightly delayed that will
                %mess with the data. 
                % Ideally these have also been trimmed to fit the length of the fire. if
                % not we should do that here.
                
                [~, swap] = min(obangvec);
                if (swap==1) % case where obang is initially closed
                    [~, swap] = max(obangvec); % time to first swap, i.e. time valve opens, starting cycle
                    [~, flop] = min(obangvec(swap:end)); % time valve closes
                    [~, swap2] = max(obangvec(swap+flop:end)); % time valve opens again, completing cycle
                    interval_opens = swap; % start of cycle, open
                    interval_closes = swap+flop+swap2; % end of cycle
                    interval_switch = swap+flop;
                    init_open = false;
                else % case where obang is initially open
                    % swap is already time to first swap, i.e. time valve closes, starting cycle
                    [~, flop] = max(obangvec(swap:end)); %time valve opens
                    [~, swap2] = min(obangvec(swap+flop:end)); %time valve closes, completing cycle
                    %[~, flop2] = max(obangvec(swap+flop+swap2:end)); %time valve closes
                    interval_opens = swap; % start of cycle, closed
                    interval_closes = swap+flop+swap2; % end of cycle
                    interval_switch = swap+flop;
                    init_open = true;
                end

                %the following tries to give myself stagnation temperatures that im going
                %to use for mdots
                stagT = zeros(len, 1);
                stagT(interval_opens:interval_closes) = T;

                %'Stagnation pressure vector' found in the same way:
                stagP = zeros(len, 1); 
                stagP(interval_opens:interval_closes) = 1500; % P(interval_opens:interval_closes)
                
                %for i = 2:length(interval_opens)
                %    stagP(interval_opens(i-1)+1:interval_opens(i)-1) = T(interval_opens(i-1));
                %end
                
                mdot_gas_actual = (stagP*A*cd) ./...
                                    (sqrt(stagT)*sqrt(gamma/R) * (((gamma+1)/2)^((gamma+1)/(2.*gamma-2))));
                %from choked flow. Assume gamma and R to be constant. stagnation pressure
                %and temperature assumed to be constant and equal to initial pressurant P and T,
                %which is essentially assuming isothermal over each open interval. 
                %Matthew Dalton said to take it - may cause errors. 
                
                %from PV = mRT, vdot = (mdot*RT/P + Tdot*RT/P - pdot*mRT)/P^2
                %we assume isobaric upstream bc of the regulator and isothermal because I
                %think we would need m_initial which would itself require upsteam volume.
                vdot_gas_actual = mdot_gas_actual*R*T ./ P;
                
                interval_open_lengths = (interval_closes-interval_opens);
                %length bangbang is open
                %this function assumes timestep is constant. If timestamps are not linear we will have to
                %change this a little bit
                                
                %open_cycles = interval_opens-interval_closes
                interval_delta_V = zeros(len,1);
                %interval_Vdots_theo = zeros(len,1);
                
                interval_delta_V(interval_opens:interval_closes) = lox_density*mdot(interval_opens:interval_closes);
                interval_Vdots_theo = interval_delta_V ./ interval_open_lengths;
                    
                %our theroetical Vdot of gas is just then the volume flow rate out the tank
                %assuming constant upstream pressure and temperature (these are stronger
                %assumptions on HETS - not valid for rockets).
                
                Vdot_gas_theo = interval_Vdots_theo;

                %now we can finally find our collapse factor
                %start with zero vector because that makes graphing intervals easier - when
                %there's no flow collapse factor = 0. zero padding might make this happen
                %anyways so it may be unnecessary.
                CollapseFactor = vdot_gas_actual ./ Vdot_gas_theo;
                CollapseFactor(isnan(CollapseFactor)) = 0;
                if init_open==true
                    CollapseFactor(interval_switch:interval_closes) = 0;
                else
                    CollapseFactor(interval_opens:interval_switch) = 0;
                end

                app.CollapseFactorBetaEditField.Value = mean(CollapseFactor(interval_opens:interval_closes));
            end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

            app.PNPTTextArea.Value = num2str(app.PNPT(1));  % PNPT Value on the GUI is just displayed as the first PNPT value recorded
            
            if app.lox~=0 && app.jeta~=0               % if both sides are flowing
                app.meanCHPT = mean(app.CHPT(i));      % mean CHPT is the average CHPT over the entire interval i
            else
                app.meanCHPT = 0;
            end
            if app.lox~=0                              % if lox side is flowing
                app.meanOIPT = mean(app.OIPT(i));      % mean OIPT is the average OIPT over the entire interval i
            end
            if app.jeta~=0                             % if fuel side is flowing
                app.meanFIPT = mean(app.FIPT(i));      % mean FIPT is the average FIPT over the entire interval i
            end

            if (app.lox==2 && app.jeta==2)             % if KeroLOX is firing
                app.avg_THRUST = mean(app.THRUST(i));  % mean Thrust is the average Thrust over the entire interval i
            end
            
            if app.SpecialboxCheckBox.Value==true
                loctimes = app.times;

                locobang = app.OBANG > 0;
                locfbang = app.FBANG > 0;
                obangfile = [loctimes', locobang];
                fbangfile = [loctimes', locfbang];
                obangname = [app.data_name(1:end-4), '_obang.csv'];
                fbangname = [app.data_name(1:end-4), '_fbang.csv'];
                writematrix(obangfile, obangname);
                writematrix(fbangfile, fbangname);

                prptofile = [loctimes', app.PRPTO];
                prptffile = [loctimes', app.PRPTF];
                prptoname = [app.data_name(1:end-4), '_prpto.csv'];
                prptfname = [app.data_name(1:end-4), '_prptf.csv'];
                writematrix(prptofile, prptoname);
                writematrix(prptffile, prptfname);

                otptfile = [loctimes', app.OTPT];
                ftptfile = [loctimes', app.FTPT];
                chptfile = [loctimes', app.CHPT];
                otptname = [app.data_name(1:end-4), '_otpt.csv'];
                ftptname = [app.data_name(1:end-4), '_ftpt.csv'];
                chptname = [app.data_name(1:end-4), '_chpt.csv'];
                writematrix(otptfile, otptname);
                writematrix(ftptfile, ftptname);
                writematrix(chptfile, chptname);

                otbtcfile = [loctimes', app.OTBTC];
                otbtcname = [app.data_name(1:end-4), '_otbtc.csv'];
                writematrix(otbtcfile, otbtcname);
            end

            if app.TextArea.Value{1}~='E'
                app.TextArea.Value = "Running (12/12)";
                pause(0.01); % This makes the Running box work
    
                app.TextArea.Value = "Finished!";
                pause(5);
                app.TextArea.Value = "Not running.";
            end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

        end

        function LoadVespulaData(app, data)
        
        end
    end

    methods (Access = private)

        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.StahlDataAnalysisUIFigure.Name = ['DANGUI ' app.versionLabel.Text];
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            thisfolder = mfilename("fullpath");
            if ~strcmp(thisfolder(end-16:end), 'StahlDataAnalysis')
                app.TextArea.Value = "Error: Don't change the filename >:(";
                pause(0.01); % This makes the Running box work
                return
            end
            thisfolder(end-17:end) = [];
            cd(thisfolder)
            
            app.TextArea.Value = "Processing (1/5)";
            pause(0.01); % This makes the Running box work

            if strcmp(app.DatasetInputLabel.Text, '<no input selected>')
                app.TextArea.Value = "Error: No dataset input selected.";
                pause(0.01); % This makes the Running box work
                return
            end

            app.OMVTimeTextArea.Value = '--';
            app.FMVTimeTextArea.Value = '--';
            app.LOXLeadEditField_3.Value = '--';
            app.PNPTTextArea.Value = '--';
            app.omvflowdurTextArea.Value = '--';
            app.fmvflowdurTextArea.Value = '--';
            app.SamplingRateTextArea.Value = '--';

            app.avgloxmdotorfTextArea.Value = '--';
            app.avgloxmdotsysTextArea.Value = '--';
            app.avgloxmdotventuriTextArea.Value = '--';
            app.avgfuelmdotorfTextArea.Value = '--';
            app.avgfuelmdotsysTextArea.Value = '--';
            app.avgfuelmdotfmTextArea.Value = '--';
            app.AvgLOXSysCdAOrificeEditField.Value = '--';
            app.AvgLOXSysCdAVenturiEditField.Value = '--';
            app.AvgLOXOrfCdASysCdAEditField.Value = '--';
            app.AvgLOXOrfCdAVenturiEditField.Value = '--';
            app.AvgFuelSysCdAOrificeEditField.Value = '--';
            app.AvgFuelSysCdAFlowMeterEditField.Value = '--';
            app.AvgFuelOrfCdASysCdAEditField.Value = '--';
            app.AvgFuelOrfCdAFlowMeterEditField.Value = '--';

            app.TextArea.Value = "Processing (2/5)";
            pause(0.01); % This makes the Running box work
            
            if app.LOXCheckBox.Value == true
                app.lox = 2;
            elseif app.LN2CheckBox.Value == true
                app.lox = 1;
            else 
                app.lox = 0;
            end

            app.TextArea.Value = "Processing (3/5)";
            pause(0.01); % This makes the Running box work

            if app.JetACheckBox.Value == true
                app.jeta = 2;
            elseif app.WaterCheckBox.Value == true
                app.jeta = 1;
            else
                app.jeta = 0;
            end
            
            app.TextArea.Value = "Processing (4/5)";
            pause(0.01); % This makes the Running box work

            LoadNewData(app, app.datacsvEditField.Value);
        end

        % Value changed function: LOXCheckBox
        function LOXCheckBoxValueChanged(app, event)
            value = app.LOXCheckBox.Value;
            if value == true
                app.LN2CheckBox.Value = false;
                app.LOXLN2densityEditFieldLabel.Text = 'LOX Density';
                app.LOXLN2densityEditField.Value = 1141;
            end
        end

        % Value changed function: LN2CheckBox
        function LN2CheckBoxValueChanged(app, event)
            value = app.LN2CheckBox.Value;
            if value == true
                app.LOXCheckBox.Value = false;
                app.LOXLN2densityEditFieldLabel.Text = 'LN2 Density';
                app.LOXLN2densityEditField.Value = 807;
            end
        end

        % Value changed function: JetACheckBox
        function JetACheckBoxValueChanged(app, event)
            value = app.JetACheckBox.Value;
            if value == true
                app.WaterCheckBox.Value = false;
            end
        end

        % Value changed function: WaterCheckBox
        function WaterCheckBoxValueChanged(app, event)
            value = app.WaterCheckBox.Value;
            if value == true
                app.JetACheckBox.Value = false;
            end
        end

        % Button pushed function: PurgeButton
        function PurgeButtonPushed(app, event)
            % Purge
            figure
            hold on
            plot(app.times, app.PUPT2)
            title('Purge Pressure')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Pressure (psig)')
            xlabel('Time (s)')
            hold off
        end

        % Button pushed function: PressureLaddersButton
        function PressureLaddersButtonPushed(app, event)
            % % Gasses plot
            if app.PRPTOCheckBox.Value==true || app.PRPTFCheckBox.Value==true
                legendcell = [];                                                    % resets legend
                figure
                hold on
                if (app.PRPT1CheckBox.Value==true)
                    plot(app.times, app.PRPT1, 'b-')
                    legendcell = [legendcell, {'PRPT1'}];
                end
                if (app.PRPT2CheckBox.Value==true)
                    plot(app.times, app.PRPT2, 'g-')
                    legendcell = [legendcell, {'PRPT2'}];
                end
                if (app.PRPTOCheckBox.Value==true)
                    plot(app.times, app.PRPTO, 'r-')
                    legendcell = [legendcell, {'PRPTO'}];
                end
                if (app.PRPTFCheckBox.Value==true)
                    plot(app.times, app.PRPTF, 'm-')
                    legendcell = [legendcell, {'PRPTF'}];
                end
                ylabel('Pressure (psig)')
                title('Gas Pressures')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend(legendcell)
                xlabel('Time (s)')
                xlim([(0-app.TEditField.Value) app.TEditField_2.Value])
                hold off
            end

            % % %LOX Side
            % % Full Pressure Ladder Plot
            if app.lox~=0 && (app.OTPTCheckBox.Value || app.ORUNPTCheckBox.Value || app.OIPTCheckBox.Value)
                legendcell = [];
                figure
                hold on
                if (app.PRPT1CheckBox.Value==true)
                    plot(app.times, app.PRPT1, 'b-')
                    legendcell = [legendcell, {'PRPT1'}];
                end
                if (app.PRPT2CheckBox.Value==true)
                    plot(app.times, app.PRPT2, 'g-')
                    legendcell = [legendcell, {'PRPT2'}];
                end
                if (app.OTPTCheckBox.Value==true)
                    otptplot = plot(app.times, app.OTPT, 'c-');
                    plot(app.times, zeros(length(app.OTPT),1)+app.OTPTTargetEditField.Value, 'c--');
                    [a,b] = max(app.OTPT(app.tzero+10:app.tend));
                    [c,d] = min(app.OTPT(app.tzero+10+b:app.tzero+10+b+50));
                    b = app.tzero+10+b;
                    d = b+d;
                    datatip(otptplot, app.times(b), a, Location='northwest')
                    datatip(otptplot, app.times(d), c, Location='northeast')
                    legendcell = [legendcell, {'OTPT'}, {'OTPT Target'}];
                end
                if (app.ORUNPTCheckBox.Value==true)
                    if app.SwitchORUNandFRUNCheckBox.Value==false
                        orunptplot = plot(app.times, app.ORUNPT, 'm-');
                        datatip(orunptplot, app.times(b), app.ORUNPT(b), Location='southeast')
                        legendcell = [legendcell, {'ORUNPT'}];
                    else
                        plot(app.times, app.FRUNPT, 'm-')
                        legendcell = [legendcell, {'ORUNPT'}];
                    end
                end
                if (app.OIPTCheckBox.Value==true)
                    oiptplot = plot(app.times, app.OIPT, 'r-');
                    datatip(oiptplot, app.times(b), app.OIPT(b), Location='southwest')
                    plot(app.times, zeros(length(app.OIPT),1)+app.OIPTTargetEditField.Value, 'r--');
                    legendcell = [legendcell, {'OIPT'}, {'OIPT Target'}];
                end
                if (app.CHPTCheckBox.Value==true)
                    chptplot = plot(app.times, app.CHPT, 'b-');
                    datatip(chptplot, app.times(b), app.CHPT(b), Location='southeast')
                    plot(app.times, zeros(length(app.CHPT),1)+app.CHPTTargetEditField.Value, 'b--');
                    legendcell = [legendcell, {'CHPT'}, {'CHPT Target'}];
                end
                ylabel('Pressure (psig)')
                title('LOX Pressure Ladder')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend(legendcell)
                xlabel('Time (s)')
                xlim([(0-app.TEditField.Value) app.TEditField_2.Value])
                hold off
    
                % % Pressure Ladder Plot (Liquid Only)
                % figure
                % hold on
                % plot(app.times, app.OTPT, 'c-')
                % plot(app.times, app.ORUNPT, 'm-')
                % plot(app.times, app.OIPT, 'r-')
                % %plot(app.times, app.CHPT, 'b-')
                % ylabel('Pressure (psig)')
                % title('LOX Pressure Ladder (Liquid Only)')
                % subtitle(app.data_name, 'Interpreter', 'none')
                % legend('OTPT', 'ORUNPT', 'OIPT', 'CHPT')
                % xlabel('Time (s)')
                % hold off
            end

            % Fuel Side
            % Full Pressure Ladder Plot
            if app.jeta~=0 && (app.FTPTCheckBox.Value || app.FRUNPTCheckBox.Value || app.FIPTCheckBox.Value || app.FINPTCheckBox.Value || app.FLPTCheckBox.Value)
                legendcell = [];
                figure
                hold on
                if (app.PRPT1CheckBox.Value==true)
                    plot(app.times, app.PRPT1, 'b-')
                    legendcell = [legendcell, {'PRPT1'}];
                end
                if (app.PRPT2CheckBox.Value==true)
                    plot(app.times, app.PRPT2, 'g-')
                    legendcell = [legendcell, {'PRPT2'}];
                end
                if (app.FTPTCheckBox.Value==true)
                    ftptplot = plot(app.times, app.FTPT, 'c-');
                    plot(app.times, zeros(length(app.FTPT),1)+app.FTPTTargetEditField.Value, 'c--');
                    [a,b] = max(app.FTPT(app.tzero+10:app.tend));
                    [c,d] = min(app.FTPT(app.tzero+10+b:app.tzero+10+b+50));
                    b = app.tzero+10+b;
                    d = b+d;
                    datatip(ftptplot, app.times(b), a, Location='northwest')
                    datatip(ftptplot, app.times(d), c, Location='northeast')
                    legendcell = [legendcell, {'FTPT'}, {'FTPT Target'}];
                end
                if (app.FRUNPTCheckBox.Value==true)
                    if app.SwitchORUNandFRUNCheckBox.Value==false
                        frunptplot = plot(app.times, app.FRUNPT, 'm-');
                        datatip(frunptplot, app.times(b), app.FRUNPT(b), Location='southeast')
                        legendcell = [legendcell, {'FRUNPT'}];
                    else
                        plot(app.times, app.ORUNPT, 'm-')
                        legendcell = [legendcell, {'FRUNPT'}];
                    end
                end
                if (app.FIPTCheckBox.Value==true)
                    fiptplot = plot(app.times, app.FIPT, 'r-');
                    datatip(fiptplot, app.times(b), app.FIPT(b), Location='southwest')
                    plot(app.times, zeros(length(app.FIPT),1)+app.FIPTTargetEditField.Value, 'r--');
                    legendcell = [legendcell, {'FIPT'}, {'FIPT Target'}];
                end
                if (app.FLPTCheckBox.Value==true && app.FLPT~=0)
                    plot(app.times, app.FLPT, 'r.')
                    legendcell = [legendcell, {'FLPT'}];
                end
                if (app.FINPTCheckBox.Value==true && app.FINPT~=0)
                    plot(app.times, app.FINPT, 'r--')
                    legendcell = [legendcell, {'FINPT'}];
                end
                if (app.CHPTCheckBox.Value==true)
                    chptplot = plot(app.times, app.CHPT, 'b-');
                    datatip(chptplot, app.times(b), app.CHPT(b), Location='southeast')
                    plot(app.times, zeros(length(app.CHPT),1)+app.CHPTTargetEditField.Value, 'b--');
                    legendcell = [legendcell, {'CHPT'}, {'CHPT Target'}];
                end
                ylabel('Pressure (psig)')
                title('Fuel Pressure Ladder')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend(legendcell)
                xlabel('Time (s)')
                xlim([(0-app.TEditField.Value) app.TEditField_2.Value])
                hold off
                % 
                % % % Pressure Ladder Plot (Liquid Only)
                % figure
                % hold on
                % plot(app.times, app.FTPT, 'c-')
                % plot(app.times, app.FRUNPT, 'm-')
                % plot(app.times, app.FIPT, 'r-')
                % %plot(app.times, FLPT, 'r.')
                % %plot(app.times, FINPT, 'r--')
                % plot(app.times, app.CHPT, 'b-')
                % ylabel('Pressure (psig)')
                % title('Fuel Pressure Ladder (Liquid Only)')
                % subtitle(app.data_name, 'Interpreter', 'none')
                % legend('FTPT', 'FRUNPT', 'FIPT', 'CHPT')
                % xlabel('Time (s)')
                % hold off
            end
            if app.PNPTCheckBox.Value==true
                % % Plot PNPT
                figure
                hold on
                plot(app.times, app.PNPT)
                plot(app.times, app.PNPT2)
                title('PNPT')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('PNPT', 'PNPT2')
                hold off
            end
            if app.FLOWCheckBox.Value==true
                % % Plot FM
                figure
                hold on
                plot(app.times, app.FLOW)
                title('Flow Meter DP')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('Flow Meter')
                hold off
            end
            if app.ODPCheckBox.Value==true
                % % Plot ODP
                figure
                hold on
                plot(app.times, app.ODP)
                title('Venturi ODP')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('ODP')
                hold off
            end
        end

        % Button pushed function: mdotsButton
        function mdotsButtonPushed(app, event)
            if app.lox~=0
                % % LOX Mdot Plot
                figure
                hold on
                plot(app.times, app.lox_mdot_cda, '-b');
                plot(app.times, app.lox_mdot_sys_cda, '-c');
                plot(app.times, app.lox_mdot_venturi, '-r');
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.TargetLOXmdotEditField.Value, 'k.');
                mdotorfplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgloxmdotorfTextArea.Value), 'b--');
                mdotsysplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgloxmdotsysTextArea.Value), 'c--');
                mdotventuriplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgloxmdotventuriTextArea.Value), 'r--');
                if app.ShowdatatipsCheckBox.Value==true
                    datatip(mdotorfplot, app.times(end), str2double(app.avgloxmdotorfTextArea.Value), Location='northwest')
                    datatip(mdotsysplot, app.times(end), str2double(app.avgloxmdotsysTextArea.Value), Location='southwest')
                    datatip(mdotventuriplot, app.times(end), str2double(app.avgloxmdotventuriTextArea.Value), Location='southwest')
                end
                title('LOX Mass Flow')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('Mass Flow (kg/s)')
                ylim([0 3])
                legend('Injector CdA', 'System CdA', 'Venturi')
                
                if (app.ShowinputsCheckBox_2.Value==true)
                    ooricda = app.lox_orifice_cda;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['Orifice CdA = ', ooricda];
                    ooricda(25) = ' ';
    
                    ooridp = app.meanOIPT-app.meanCHPT;
                    ooridp = num2str(round(ooridp, 3));
                    ooridp = ['Orifice dP = ' ooridp];
                    ooridp(25) = ' ';
    
                    osyscda = app.lox_sys_cda;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['System CdA = ', osyscda];
                    osyscda(25) = ' ';
    
                    osysdp = mean(app.OTPT)-app.meanOIPT;
                    osysdp = num2str(round(osysdp, 3));
                    osysdp = ['System dP = ' osysdp];
                    osysdp(25) = ' ';

                    loxrho = app.LOXLN2densityEditField.Value;
                    loxrho = num2str(round(loxrho, 3));
                    loxrho = ['LOx Density = ' loxrho];
                    loxrho(25) = ' ';
    
                    annotation('textbox', [0.15, 0.3, 0.1, 0.1], 'String', [ooricda; ooridp; osyscda; osysdp; loxrho])
                end
                hold off
            end

            if app.jeta~=0
                % % Fuel Mdot Plot
                figure
                hold on
                plot(app.times, app.fuel_mdot_cda, '-b');
                plot(app.times, app.fuel_mdot_sys_cda, '-c');
                plot(app.times, app.fuel_mdot_fm, '-r');
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.TargetFuelmdotEditField.Value, 'k.');
                mdotorfplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgfuelmdotorfTextArea.Value), '--b');
                mdotsysplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgfuelmdotsysTextArea.Value), '--c');
                mdotfmplot = plot(app.times, zeros(length(app.times),1) + str2double(app.avgfuelmdotfmTextArea.Value), '--r');
                if app.ShowdatatipsCheckBox.Value==true
                    datatip(mdotorfplot, app.times(end), str2double(app.avgfuelmdotorfTextArea.Value), Location='northwest')
                    datatip(mdotsysplot, app.times(end), str2double(app.avgfuelmdotsysTextArea.Value), Location='southwest')
                    datatip(mdotfmplot, app.times(end), str2double(app.avgfuelmdotfmTextArea.Value), Location='southwest')
                end
                title('Fuel Mass Flow')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('Mass Flow (kg/s)')
                legend('Injector CdA', 'System CdA', 'Flow Meter')
                if (app.ShowinputsCheckBox_2.Value==true)
                    foricda = app.fuel_orifice_cda;
                    foricda = num2str(round(foricda, 3));
                    foricda = ['Orifice CdA = ', foricda];
                    foricda(20) = ' ';
    
                    foridp = app.meanFIPT-app.meanCHPT;
                    foridp = num2str(round(foridp, 3));
                    foridp = ['Orifice dP = ' foridp];
                    foridp(20) = ' ';
    
                    fsyscda = app.fuel_sys_cda;
                    fsyscda = num2str(round(fsyscda, 3));
                    fsyscda = ['System CdA = ', fsyscda];
                    fsyscda(20) = ' ';
    
                    fsysdp = mean(app.FTPT)-app.meanFIPT;
                    fsysdp = num2str(round(fsysdp, 3));
                    fsysdp = ['System dP = ' fsysdp];
                    fsysdp(20) = ' ';
    
                    annotation('textbox', [0.15, 0.2, 0.1, 0.1], 'String', [foricda; foridp; fsyscda; fsysdp])
                end
                hold off
            end
        end

        % Button pushed function: CdAsButton
        function CdAsButtonPushed(app, event)
            if app.lox~=0
                % % Plot System CdA
                figure
                hold on
                plot(app.times, app.lox_sys_cda_orifice, '-b')
                plot(app.times, app.lox_sys_cda_venturi, '-r')
                cdaorfplot = plot(app.times, zeros(length(app.lox_sys_cda_orifice),1)+str2double(app.AvgLOXSysCdAOrificeEditField.Value), '--b');
                cdaventuriplot = plot(app.times, zeros(length(app.lox_sys_cda_venturi),1)+str2double(app.AvgLOXSysCdAVenturiEditField.Value), '--r');
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.LOXSystemCdAEditField.Value, 'k.');
                if app.ShowdatatipsCheckBox_2.Value==true
                    datatip(cdaorfplot, app.times(end), str2double(app.AvgLOXSysCdAOrificeEditField.Value), Location='northwest')
                    datatip(cdaventuriplot, app.times(end), str2double(app.AvgLOXSysCdAVenturiEditField.Value), Location='southwest')
                end
                title('LOX System CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('Injector', 'Venturi', Location='northwest')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                ylim([0, app.lox_sys_cda*1.5])
                if (app.ShowinputsCheckBox.Value==true)
                    ooricda = app.avg_lox_mdot_orifice;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['Orifice mdot = ', ooricda];
                    ooricda(25) = ' ';
    
                    osyscda = app.avg_lox_mdot_venturi;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['Venturi mdot = ', osyscda];
                    osyscda(25) = ' ';
    
                    osysdp = app.meanOIPT-app.meanCHPT;
                    osysdp = num2str(round(osysdp, 3));
                    osysdp = ['System dP = ' osysdp];
                    osysdp(25) = ' ';

                    loxrho = app.LOXLN2densityEditField.Value;
                    loxrho = num2str(round(loxrho, 3));
                    loxrho = ['LOx Density = ' loxrho];
                    loxrho(25) = ' ';
    
                    annotation('textbox', [0.15, 0.25, 0.1, 0.1], 'String', [ooricda; osyscda; osysdp; loxrho])
                end
                hold off

                % Plot Injector CdA
                OIPT_mask = app.OIPT > 10;
                figure
                hold on
                thesetimes = app.times(OIPT_mask);
                plot(thesetimes, app.lox_orf_cda_sys_cda(OIPT_mask), '-c');
                plot(thesetimes, app.lox_orf_cda_venturi(OIPT_mask), '-r');
                cdainjplot = plot(thesetimes, zeros(length(app.lox_orf_cda_venturi(OIPT_mask)),1)+app.lox_orifice_cda, '--g');
                cdasysplot = plot(thesetimes, zeros(length(app.lox_orf_cda_sys_cda(OIPT_mask)),1)+str2double(app.AvgLOXOrfCdASysCdAEditField.Value), '--c');
                cdaventuriplot = plot(thesetimes, zeros(length(app.lox_orf_cda_venturi(OIPT_mask)),1)+str2double(app.AvgLOXOrfCdAVenturiEditField.Value), '--r');
                plot(thesetimes(1:20:end), zeros(length(thesetimes(1:20:end)),1)+app.LOXInjectorCdAEditField.Value, 'k.');
                if app.ShowdatatipsCheckBox_2.Value==true
                    datatip(cdasysplot, thesetimes(end), str2double(app.AvgLOXOrfCdASysCdAEditField.Value), Location='northwest')
                    datatip(cdaventuriplot, thesetimes(end), str2double(app.AvgLOXOrfCdAVenturiEditField.Value), Location='southwest')
                    datatip(cdainjplot, thesetimes(end), app.lox_orifice_cda, Location='southeast')
                end
                title('LOX Injector CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('System CdA', 'Venturi', 'Injector CdA', Location='northwest')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                %ylim([0, app.lox_orifice_cda*1.5])
                if (app.ShowinputsCheckBox.Value==true)
                    ooricda = app.avg_lox_mdot_sys_cda;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['Orifice mdot w/ System CdA = ', ooricda];
                    ooricda(35) = ' ';
    
                    ooridp = app.meanOIPT-app.meanCHPT;
                    ooridp = num2str(round(ooridp, 3));
                    ooridp = ['Orifice dP = ' ooridp];
                    ooridp(35) = ' ';
    
                    osyscda = app.avg_lox_mdot_venturi;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['Venturi mdot = ', osyscda];
                    osyscda(35) = ' ';

                    loxrho = app.LOXLN2densityEditField.Value;
                    loxrho = num2str(round(loxrho, 3));
                    loxrho = ['LOx Density = ' loxrho];
                    loxrho(35) = ' ';
    
                    annotation('textbox', [0.15, 0.25, 0.1, 0.1], 'String', [ooricda; osyscda; ooridp; loxrho])
                end
                hold off
            end

            if app.jeta~=0
                % % Plot System CdA
                figure
                hold on
                plot(app.times, app.fuel_sys_cda_orifice, '-b');
                plot(app.times, app.fuel_sys_cda_fm, '-r');
                cdaorfplot = plot(app.times, zeros(length(app.fuel_sys_cda_orifice),1)+str2double(app.AvgFuelSysCdAOrificeEditField.Value), '--b');
                cdafmplot = plot(app.times, zeros(length(app.fuel_sys_cda_fm),1)+str2double(app.AvgFuelSysCdAFlowMeterEditField.Value), '--r');
                plot(app.times(1:20:end), zeros(length(app.times(1:20:end)),1)+app.FuelSystemCdAEditField.Value, 'k.');
                if app.ShowdatatipsCheckBox_2.Value==true
                    datatip(cdaorfplot, app.times(end), str2double(app.AvgFuelSysCdAOrificeEditField.Value), Location='northwest')
                    datatip(cdafmplot, app.times(end), str2double(app.AvgFuelSysCdAFlowMeterEditField.Value), Location='southwest')
                end
                title('Fuel System CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                legend('Injector', 'Flow Meter', Location='northwest')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                if (app.ShowinputsCheckBox.Value==true)
                    ooricda = app.avg_fuel_mdot_orifice;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['Orifice mdot = ', ooricda];
                    ooricda(20) = ' ';
    
                    ooridp = mean(app.FTPT)-app.meanFIPT;
                    ooridp = num2str(round(ooridp, 3));
                    ooridp = ['System dP = ' ooridp];
                    ooridp(20) = ' ';
    
                    osyscda = app.avg_fuel_mdot_fm;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['FM mdot = ', osyscda];
                    osyscda(20) = ' ';
    
                    annotation('textbox', [0.15, 0.2, 0.1, 0.1], 'String', [ooricda; osyscda; ooridp])
                end
                hold off

                % Plot Injector CdA
                FIPT_mask = app.FIPT > 10;
                figure
                hold on
                thesetimes = app.times(FIPT_mask);
                plot(thesetimes, app.fuel_orf_cda_sys_cda(FIPT_mask), '-c');
                plot(thesetimes, app.fuel_orf_cda_fm(FIPT_mask), '-r');
                cdainjplot = plot(thesetimes, zeros(length(app.fuel_orf_cda_fm(FIPT_mask)),1)+app.fuel_orifice_cda, '--g');
                cdasysplot = plot(thesetimes, zeros(length(app.fuel_orf_cda_fm(FIPT_mask)),1)+str2double(app.AvgFuelOrfCdASysCdAEditField.Value), '--c');
                cdafmplot = plot(thesetimes, zeros(length(app.fuel_orf_cda_fm(FIPT_mask)),1)+str2double(app.AvgFuelOrfCdAFlowMeterEditField.Value), '--r');
                plot(thesetimes(1:20:end), zeros(length(thesetimes(1:20:end)),1)+app.FuelInjectorCdAEditField.Value, 'k.');
                if app.ShowdatatipsCheckBox_2.Value==true
                    datatip(cdasysplot, thesetimes(end), str2double(app.AvgFuelOrfCdASysCdAEditField.Value), Location='northwest')
                    datatip(cdafmplot, thesetimes(end), str2double(app.AvgFuelOrfCdAFlowMeterEditField.Value), Location='southwest')
                    datatip(cdainjplot, thesetimes(end), app.fuel_orifice_cda, Location='southeast')
                end
                title('Fuel Injector CdA')
                subtitle(app.data_name, 'Interpreter', 'none')
                xlabel('Time (s)')
                ylabel('CdA (cm^2)')
                ylim([0, app.fuel_orifice_cda*1.5])
                legend('System CdA', 'Flow Meter', 'Injector CdA', Location='northwest')
                if (app.ShowinputsCheckBox.Value==true)
                    ooricda = app.avg_fuel_mdot_sys_cda;
                    ooricda = num2str(round(ooricda, 3));
                    ooricda = ['System mdot = ', ooricda];
                    ooricda(20) = ' ';
    
                    ooridp = app.meanFIPT-app.meanCHPT;
                    ooridp = num2str(round(ooridp, 3));
                    ooridp = ['Orifice dP = ' ooridp];
                    ooridp(20) = ' ';
    
                    osyscda = app.avg_fuel_mdot_fm;
                    osyscda = num2str(round(osyscda, 3));
                    osyscda = ['FM mdot = ', osyscda];
                    osyscda(20) = ' ';
    
                    annotation('textbox', [0.15, 0.2, 0.1, 0.1], 'String', [ooricda; osyscda; ooridp])
                end
                hold off
            end
        end

        % Button pushed function: ValvesButton
        function ValvesButtonPushed(app, event)
            % Sense Wire Break Time
            SENSE_mask = app.SENSE > 4;
            
            legendcell = [];

            % % Plot Valves / Igniters
            figure
            hold on
            ylabel('Valve State')
            yyaxis left
            if app.lox~=0
                plot(app.times, app.OIPT, 'c-')
                legendcell = [legendcell, {'OIPT'}];
            end
            if app.jeta~=0
                exist('finpt_index', 'var')
                plot(app.times, app.FIPT, 'm-')
                legendcell = [legendcell, {'FIPT'}];
                if app.FLPT~=0
                    plot(app.times, app.FLPT, 'm--')
                    legendcell = [legendcell, {'FLPT'}];
                end
                if app.FINPT~=0
                    plot(app.times, app.FINPT, 'm-.')
                    legendcell = [legendcell, {'FINPT'}];
                end
            end
            if app.WithPressuresCheckBox.Value==true
                if app.lox~=0
                    plot(app.times(1:20:end), app.OTPT(1:20:end), 'c-square')
                    legendcell = [legendcell, {'OTPT'}];
                end
                if app.jeta~=0
                    plot(app.times(1:20:end), app.FTPT(1:20:end), 'm-square')
                    legendcell = [legendcell, {'FTPT'}];
                end
                plot(app.times, app.PNPT, 'b--')
                legendcell = [legendcell, {'PNPT'}];
            end
            ylabel('Pressure (psig)')
            yyaxis right
            if app.lox~=0
                plot(app.times, app.OMV, 'b-x')
                legendcell = [legendcell, {'OMV'}];
            end
            if app.jeta~=0
                plot(app.times, app.FMV, 'r-o')
                legendcell = [legendcell, {'FMV'}];
            end
            if app.lox~=0
                plot(app.times, app.PUO, 'g-')
                legendcell = [legendcell, {'PUO'}];
            end
            if app.jeta~=0
                plot(app.times, app.PUF,'y-')
                legendcell = [legendcell, {'PUF'}];
            end
            if app.lox==2 && app.jeta==2
                plot(app.times, app.IGNITER1, 'r--')
                plot(app.times, app.IGNITER2,'m--')
                plot(app.times, SENSE_mask,'g--')
                legendcell = [legendcell, {'IGNITER1'}, {'IGNITER2'}, {'SENSE'}];
            end
            title('Valve Timings')
            subtitle(app.data_name, 'Interpreter', 'none')
            xlabel('Time (s)')
            legend(legendcell)
            hold off
        end

        % Button pushed function: ThrustButton
        function ThrustButtonPushed(app, event)
            % % Thrust
            THRUST_zero = mean(app.THRUST(1:round(1/app.deltat)));
            app.THRUST = abs(app.THRUST-THRUST_zero);
            figure
            hold on
            yyaxis left
            thrustplot = plot(app.times, app.THRUST, 'b-');
            avgthrustplot = plot(app.times, zeros(length(app.THRUST),1)+abs(app.avg_THRUST), 'b--');
            plot(app.times, zeros(length(app.THRUST),1)+THRUST_zero, 'g--')
            [a,b] = max(app.THRUST(app.tzero:app.tend));
            datatip(thrustplot, app.times(app.tzero+b), a)
            datatip(avgthrustplot, app.times(1), -app.avg_THRUST)
            title('Thrust')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Load (lbf)')
            xlabel('Time (s)')
            yyaxis right
            plot(app.times, app.CHPT, 'r-')
            ylabel('Pressure (psig)')
            legend('Tared Thrust', 'Avg Thrust', 'Tare', 'CHPT')
            hold off

            % Thrust Coefficient
            thrust_coefficient = app.THRUST.*4.448./(app.CHPT.*6895.*app.throat_area);  % formula for thrust coefficient

            % % Thrust Coefficient Plot
            figure
            hold on
            yyaxis left
            plot(app.times, app.THRUST, 'r')
            ylabel('Thrust (lbf)')
            yyaxis right
            plot(app.times, thrust_coefficient, 'c--')
            ylim([0 2])
            ylabel('Thrust Coefficient')
            xlabel('Time (s)')
            title('Thrust Coefficient')
            subtitle(app.data_name, 'Interpreter', 'none')
            legend('Thrust', 'Thrust Coefficient')
            hold off

            % Isp
            app.ISP = (app.THRUST.*4.448)./((app.fuel_mdot_sys_cda + app.lox_mdot_venturi)*9.81);                      % formula for Specific Impulse using best mdots as of 2/2/25
            app.ISPavg_values = (app.THRUST.*4.448)./((app.avg_fuel_mdot_sys_cda + app.avg_lox_mdot_venturi)*9.81);    % formula for Specific Impulse using best average mdots as of 2/2/25

            % Isp Plots
            figure
            hold on 
            yyaxis left
            plot(app.times, app.ISP, 'r')
            plot(app.times, app.ISPavg_values, 'g')
            ylabel('Isp (m/s)')
            xlabel('Time (s)')
            title('Specific Impulse')
            subtitle(app.data_name, 'Interpreter', 'none')
            legend('Isp', 'Isp avg values')
            hold off
        end

        % Button pushed function: EngineDataButton
        function EngineDataButtonPushed(app, event)
            %Engine
            % Select Mdot Measurement Method
            lox_mdot = app.lox_mdot_venturi;
            fuel_mdot_flomtr = app.fuel_mdot_fm; %named weird bc app tries to reference different variable
            fuel_mdot_syscda = app.fuel_mdot_sys_cda; %same
            fuel_mdot_injcda = app.fuel_mdot_cda;
            MRfm = lox_mdot./fuel_mdot_flomtr;
            MRsys = lox_mdot./fuel_mdot_syscda;
            MRinj = lox_mdot./fuel_mdot_injcda;
            MRfm(1:app.tzero) = 0;
            MRfm(app.tend:end) = 0;
            MRsys(1:app.tzero) = 0;
            MRsys(app.tend:end) = 0;
            MRinj(1:app.tzero) = 0;
            MRinj(app.tend:end) = 0;

            % Everything Engine Plot fm
            figure
            hold on
            yyaxis left
            ylabel('Pressure (psig)')
            plot(app.times, app.CHPT, 'g-')
            plot(app.times, app.OIPT, 'c-')
            plot(app.times, app.FIPT, 'm-')
            yyaxis right
            ylabel('Mass Flow (kg/s)')
            plot(app.times, lox_mdot, 'b-')
            plot(app.times, fuel_mdot_flomtr, 'r-')
            plot(app.times, MRfm, '-')
            ylim([0 3])
            legend('CHPT', 'OIPT', 'FIPT', 'LOX Mdot', 'Fuel Mdot', 'MR')
            xlabel('Time (s)')
            title('Engine Data - Flow Meter')
            subtitle(app.data_name, 'Interpreter', 'none')
            hold off

            % Everything Engine Plot sys cda
            figure
            hold on
            yyaxis left
            ylabel('Pressure (psig)')
            plot(app.times, app.CHPT, 'g-')
            plot(app.times, app.OIPT, 'c-')
            plot(app.times, app.FIPT, 'm-')
            yyaxis right
            ylabel('Mass Flow (kg/s)')
            plot(app.times, lox_mdot, 'b-')
            plot(app.times, fuel_mdot_syscda, 'r-')
            plot(app.times, MRsys, '-')
            ylim([0 3])
            legend('CHPT', 'OIPT', 'FIPT', 'LOX Mdot', 'Fuel Mdot', 'MR')
            xlabel('Time (s)')
            title('Engine Data - System CdA')
            subtitle(app.data_name, 'Interpreter', 'none')
            hold off

            % Everything Engine Plot inj cda
            figure
            hold on
            yyaxis left
            ylabel('Pressure (psig)')
            plot(app.times, app.CHPT, 'g-')
            plot(app.times, app.OIPT, 'c-')
            plot(app.times, app.FIPT, 'm-')
            yyaxis right
            ylabel('Mass Flow (kg/s)')
            plot(app.times, lox_mdot, 'b-')
            plot(app.times, fuel_mdot_injcda, 'r-')
            plot(app.times, MRinj, '-')
            ylim([0 3])
            legend('CHPT', 'OIPT', 'FIPT', 'LOX Mdot', 'Fuel Mdot', 'MR')
            xlabel('Time (s)')
            title('Engine Data - Injector CdA')
            subtitle(app.data_name, 'Interpreter', 'none')
            hold off
        end

        % Button pushed function: TemperaturesButton
        function TemperaturesButtonPushed(app, event)
            % Plot LOX Temps
             figure
             hold on
             plot(app.times, app.OTBTC)
             %plot(app.times, app.OTUTC)
             plot(app.times, app.OFMTC)
             plot(app.times, app.OTTC1)
             plot(app.times, app.OTTC2)
             plot(app.times, app.OTTC3)
             plot(app.times, app.OITC)
             title('LOX Temperatures')
             subtitle(app.data_name, 'Interpreter', 'none')
             ylabel('Temperature (K)')
             xlabel('Time (s)')
             legend('OTBTC','OFMTC', 'OTTC1', 'OTTC2', 'OTTC3', 'OITC')
             hold off
            
            % % Plot Engine Temps
            if 1~=0
                figure
                hold on
                plot(app.times, app.HFTC1)
                plot(app.times, app.HFTC2)
                title('Heat Flux Temperatures')
                subtitle(app.data_name, 'Interpreter', 'none')
                ylabel('Temperature (K)')
                xlabel('Time (s)')
                legend('HFTC1','HFTC2')
                hold off
            end

            % % Plot Engine Temps
            if 1~=0
                figure
                hold on
                plot(app.times, app.ETC1)
                plot(app.times, app.ETC3)
                title('Engine Temperatures')
                subtitle(app.data_name, 'Interpreter', 'none')
                ylabel('Temperature (K)')
                xlabel('Time (s)')
                legend('ETC1','ETC3')
                hold off
            end

            % % Plot Fuel Temps
            % figure
            % hold on
            % plot(app.times, app.FTUTC)
            % title('Fuel Temperatures')
            % subtitle(app.data_name, 'Interpreter', 'none')
            % ylabel('Temperature (K)')
            % xlabel('Time (s)')
            % legend('FTUTC')
            % hold off
            % 
            % % Plot Engine Temps
            % figure
            % hold on
            % plot(app.times, app.ETC1)
            % plot(app.times, app.ETC2)
            % plot(app.times, app.ETC3)
            % % plot(app.times, ETC4)
            % title('Engine Temperatures')
            % subtitle(app.data_name, 'Interpreter', 'none')
            % ylabel('Temperature (K)')
            % xlabel('Time (s)')
            % legend('ETC1', 'ETC2', 'ETC3', 'ETC4')
            % hold off  
        end

        % Button pushed function: CEfficiencyButton
        function CEfficiencyButtonPushed(app, event)
            % Calculates Theoretical C* 
            
            % Dario's Tool, if this doesn't work then use basic
            % calculation 
            if app.jeta == 2
                fuel = "Jet-A(L)";
            end
            if app.lox == 2
                oxidizer = "O2(L)";
            end
            
            cd NASA_CEA_Package
            
            % Assigns file name used for the CEA toolF
            fileName = fullfile("" + fuel + "_" + oxidizer + "_ro");
            txtFileName = fullfile("input.txt");

            % Writes name of file in input.txt. to be used as input for the
            % run.bat file when called later
            writematrix(fileName, txtFileName);

            Pc = app.meanCHPT;
            pressureUnit = "bar";

            avg_MR_lox_venturi_fuel_sys = (app.avg_lox_mdot_venturi/app.avg_fuel_mdot_sys_cda);
            ceaInput = ["problem";"ro";"fac";"ac/at="+6;"p,"+pressureUnit+"="+Pc;"supar= 3.2";"o/f="+avg_MR_lox_venturi_fuel_sys;"reactant";"fu "+fuel+" wt%=100";"ox "+oxidizer+" wt%=100";"output short";"output siunits";"output massf";"output transport";"plot t gam m cf";"end"];
            writematrix(ceaInput,fileName+'.txt','QuoteStrings',0);
            % need to convert the .txt file to a . inp since writematrix
            % would not work with .inp
            % 4 lines below are from the mathworks forum:
            % https://www.mathworks.com
            txtFile = fileName + '.txt';
            inpFile = strrep(txtFile,'.txt','.inp');
            copyfile(txtFile,inpFile);
            delete(fileName+'.txt');

            % run CEA Solver
            system('"run.bat"');

            % Collect results
            CEAResults = readmatrix(fileName+".csv");
            
            cd ..

            %CEAResults(row, col)
            %row 2 is combustion end
            %column 1 is Temp
            %Column 2 is gamma
            %Column 3 is molecular weight
            T = CEAResults(2,1); %K
            gamma = CEAResults(2,2);
            mw = CEAResults(2,3);
            R = 8314.5/mw;
            
            Cstar_theo = (sqrt(gamma*T*R))/(gamma*sqrt((2/(gamma+1))^((gamma+1)/(gamma-1)))); % Theoretical C* for an ideally expanded engine

            % Saaketh Ideal C* idea (in case CEA pull doesn't work)
            % Cstar_theo = ((6895.*app.CHPTTargetEditField.Value).*(app.throat_area))./(app.TargetFuelmdotEditField.Value + app.TargetLOXmdotEditField.Value);
 

            % Calculates Actual C*
            Cstar_act = ((app.CHPT*6895).*(app.throat_area))./(app.fuel_mdot_sys_cda + app.lox_mdot_venturi);

            % Plots C* Efficiency
            Cstar_efficiency = ((Cstar_act)./(Cstar_theo)).*100;

            figure
            hold on
            plot(app.times,Cstar_efficiency)
            title('C* Efficiency')
            subtitle(app.data_name, 'Interpreter', 'none')
            ylabel('Efficiency (%)')
            xlabel('Time (s)')
            legend('C* Efficiency')
            hold off
        end

        % Button pushed function: PlotAllButton
        function PlotAllButtonPushed(app, event)
            % PurgePlot(app);
            % PressuresPlots(app);
            % MDotPlots(app);
            % CdAsPlots(app);
            % ValvesPlot(app);
            % ThrustPlots(app);
            % EngineDataPlot(app);
            % TemperaturePlots(app);
            % CstarEfficiencyPlot(app);
        end

        % Button pushed function: CloseAllButton
        function CloseAllButtonPushed(app, event)
            close all
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.loxorfcdaTextArea.Value = app.LOXInjectorCdAEditField.Value;
            app.loxsyscdaTextArea.Value = app.LOXSystemCdAEditField.Value;
            app.fuelorfcdaTextArea.Value = app.FuelInjectorCdAEditField.Value;
            app.fuelsyscdaTextArea.Value = app.FuelSystemCdAEditField.Value;

            app.OTPTTargetEditField.Value = app.OTPTTargetEditField_2.Value;
            app.FTPTTargetEditField.Value = app.FTPTTargetEditField_2.Value;
            app.OIPTTargetEditField.Value = app.OIPTTargetEditField_2.Value;
            app.FIPTTargetEditField.Value = app.FIPTTargetEditField_2.Value;
            app.CHPTTargetEditField.Value = app.CHPTTargetEditField_2.Value;
            app.TargetLOXmdotEditField.Value = app.TargetLOXmdotEditField_2.Value;
            app.TargetFuelmdotEditField.Value = app.TargetFuelmdotEditField_2.Value;

            app.LOXLeadEditField.Value = app.LOXLeadEditField_4.Value;
            app.OMVTimeEditField.Value = app.OMVTimeEditField_2.Value;
            app.FMVTimeEditField.Value = app.FMVTimeEditField_2.Value;

            app.DatasetInputLabel.Text = app.DatasetDropDown.Value;
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            value = app.DatasetDropDown.Value;
            % Complete:
            % 02.22.25 SS HS Heat Flux 2
            % 02.22.25 Vespula Heatsink 3.5
            % 02.01.25 Vespula Heatscrub
            % 11.23.24 Subscale Heatsink Heat Flux
            % 11.10.24 Vespula Main Valves Fire
            % 11.09.24 Vespula Flows New Sequence
            % 10.19.24 Subscale Ablative V2.5
            % 10.19.24 Vespula Heatsink
            % 10.06.24 Subscale Ablative
            % 10.05.24 Vespula Flows
            % 09.28.24 Vespula Flows
            % 09.21.24 Subscale Coax Remachined
            % 09.15.24 Cold Flows
            % 04.28.24 Regen Ramp Up
            % 04.28.24 Ablative Fire
            % 04.27.24 Pintle Ramp Up
            % 04.20.24 Subscale Coax 5
            % 04.20.24 Subscale Coax 4
            % 04.13.24 Coax Ramp Up
            % Next:
            % 04.07.24 Subscale Pintle 1
            % 04.07.24 Subscale Coax 3
            % 03.31.24 Subscale Coax 2
            % 03.30.24 Subscale Coax 1
            
            if strcmp(value, '02.22.25 SS HS Heat Flux 2')
                app.LOXInjectorCdAEditField.Value = 0.31;
                app.LOXSystemCdAEditField.Value = 0.85;
                app.FuelInjectorCdAEditField.Value = 0.260;
                app.FuelSystemCdAEditField.Value = 0.300;

                app.OTPTTargetEditField_2.Value = 354;
                app.FTPTTargetEditField_2.Value = 334;
                app.OIPTTargetEditField_2.Value = 342;
                app.FIPTTargetEditField_2.Value = 301;
                app.CHPTTargetEditField_2.Value = 246.3;
                app.TargetLOXmdotEditField_2.Value = 1.2;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '02.22.25 Vespula Heatsink 3.5')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 0.905;
                app.FuelInjectorCdAEditField.Value = 0.555;
                app.FuelSystemCdAEditField.Value = 0.32;

                app.OTPTTargetEditField_2.Value = 391;
                app.FTPTTargetEditField_2.Value = 547;
                app.OIPTTargetEditField_2.Value = 322;
                app.FIPTTargetEditField_2.Value = 351;
                app.CHPTTargetEditField_2.Value = 285.5;
                app.TargetLOXmdotEditField_2.Value = 2.986;
                app.TargetFuelmdotEditField_2.Value = 1.493;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Vespula - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '02.01.25 Vespula Heatscrub')
                app.LOXInjectorCdAEditField.Value = 1.25;
                app.LOXSystemCdAEditField.Value = 0.905;
                app.FuelInjectorCdAEditField.Value = 0.555;
                app.FuelSystemCdAEditField.Value = 0.32;

                app.OTPTTargetEditField_2.Value = 391;
                app.FTPTTargetEditField_2.Value = 547;
                app.OIPTTargetEditField_2.Value = 322;
                app.FIPTTargetEditField_2.Value = 351;
                app.CHPTTargetEditField_2.Value = 285.5;
                app.TargetLOXmdotEditField_2.Value = 2.986;
                app.TargetFuelmdotEditField_2.Value = 1.493;

                app.LOXLeadEditField_4.Value = 100;
                app.OMVTimeEditField_2.Value = 130;
                app.FMVTimeEditField_2.Value = 120;

                app.EngineClassDropDown.Value = 'Vespula - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '11.24.24 Subscale Heatsink Heat Flux')
                app.LOXInjectorCdAEditField.Value = 0.31;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 0.260;
                app.FuelSystemCdAEditField.Value = 0.341;

                app.OTPTTargetEditField_2.Value = 354;
                app.FTPTTargetEditField_2.Value = 334;
                app.OIPTTargetEditField_2.Value = 342;
                app.FIPTTargetEditField_2.Value = 301;
                app.CHPTTargetEditField_2.Value = 246.3;
                app.TargetLOXmdotEditField_2.Value = 1.2;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value, '11.16.24 Vespula Main Valves Fire')
                app.LOXInjectorCdAEditField.Value = 0.908;
                app.LOXSystemCdAEditField.Value = 0.973;
                app.FuelInjectorCdAEditField.Value = 0.5553;
                app.FuelSystemCdAEditField.Value = 0.344;

                app.OTPTTargetEditField_2.Value = 450;
                app.FTPTTargetEditField_2.Value = 610;
                app.OIPTTargetEditField_2.Value = 366;
                app.FIPTTargetEditField_2.Value = 362;
                app.CHPTTargetEditField_2.Value = 285;
                app.TargetLOXmdotEditField_2.Value = 3.24;
                app.TargetFuelmdotEditField_2.Value = 1.62;

                app.LOXLeadEditField_4.Value = 100;
                app.OMVTimeEditField_2.Value = 130;
                app.FMVTimeEditField_2.Value = 120;

                app.EngineClassDropDown.Value = 'Vespula - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'11.16.24 Vespula Flows New Sequence')
                app.LOXInjectorCdAEditField.Value = 0.908;
                app.LOXSystemCdAEditField.Value = 0.973;
                app.FuelInjectorCdAEditField.Value = 0.507;
                app.FuelSystemCdAEditField.Value = 0.344;

                app.OTPTTargetEditField_2.Value = 214;
                app.FTPTTargetEditField_2.Value = 277;
                app.OIPTTargetEditField_2.Value = 114;
                app.FIPTTargetEditField_2.Value = 77;
                app.CHPTTargetEditField_2.Value = 0;
                app.TargetLOXmdotEditField_2.Value = 3.24;
                app.TargetFuelmdotEditField_2.Value = 1.62;

                app.EngineClassDropDown.Value = 'Vespula - HETS';

                app.LOXLeadEditField_4.Value = 0;
                app.OMVTimeEditField_2.Value = 0;
                app.FMVTimeEditField_2.Value = 0;

                app.NotesLabel.Text = 'Note: Expected valve timings don''t matter for flows.';
            end
            if strcmp(value,'10.19.24 Subscale Ablative V2.5')
                app.LOXInjectorCdAEditField.Value = 0.31;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.2300;

                app.OTPTTargetEditField_2.Value = 325;
                app.FTPTTargetEditField_2.Value = 340;
                app.OIPTTargetEditField_2.Value = 314;
                app.FIPTTargetEditField_2.Value = 285;
                app.CHPTTargetEditField_2.Value = 246.3;
                app.TargetLOXmdotEditField_2.Value = 1.01;
                app.TargetFuelmdotEditField_2.Value = 0.56;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'10.19.24 Vespula Heatsink')
                app.LOXInjectorCdAEditField.Value = 0.908;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 0.5553;
                app.FuelSystemCdAEditField.Value = 0.482;

                app.OTPTTargetEditField_2.Value = 421;
                app.FTPTTargetEditField_2.Value = 477;
                app.OIPTTargetEditField_2.Value = 334;
                app.FIPTTargetEditField_2.Value = 376;
                app.CHPTTargetEditField_2.Value = 285;
                app.TargetLOXmdotEditField_2.Value = 3.2;
                app.TargetFuelmdotEditField_2.Value = 1.61;

                app.EngineClassDropDown.Value = 'Vespula - HETS';

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.NotesLabel.Text = 'Note: ORUNPT and FRUNPT are switched.';
                app.SwitchORUNandFRUNCheckBox.Value = true;
            end
            if strcmp(value,'10.06.24 Subscale Ablative')
                app.LOXInjectorCdAEditField.Value = 0.31;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.482;

                app.OTPTTargetEditField_2.Value = 325;
                app.FTPTTargetEditField_2.Value = 297;
                app.OIPTTargetEditField_2.Value = 314;
                app.FIPTTargetEditField_2.Value = 285;
                app.CHPTTargetEditField_2.Value = 246.3;
                app.TargetLOXmdotEditField_2.Value = 1.01;
                app.TargetFuelmdotEditField_2.Value = 0.56;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'10.05.24 Vespula Flows')
                app.LOXInjectorCdAEditField.Value = 1.1;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 1.26;
                app.FuelSystemCdAEditField.Value = 0.482;

                app.OTPTTargetEditField_2.Value = 141;
                app.FTPTTargetEditField_2.Value = 115;
                app.OIPTTargetEditField_2.Value = 54;
                app.FIPTTargetEditField_2.Value = 15;
                app.CHPTTargetEditField_2.Value = 0;
                app.TargetLOXmdotEditField_2.Value = 3.2;
                app.TargetFuelmdotEditField_2.Value = 1.61;

                app.LOXLeadEditField_4.Value = 0;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Note: Fuel and LN2 are in separate files.';
            end
            if strcmp(value,'09.28.24 Vespula Flows')
                app.LOXInjectorCdAEditField.Value = 0.504;
                app.LOXSystemCdAEditField.Value = 0.865;
                app.FuelInjectorCdAEditField.Value = 0.304333;
                app.FuelSystemCdAEditField.Value = 0.475;

                app.OTPTTargetEditField_2.Value = 485;
                app.FTPTTargetEditField_2.Value = 356;
                app.OIPTTargetEditField_2.Value = 362;
                app.FIPTTargetEditField_2.Value = 252;
                app.CHPTTargetEditField_2.Value = 0;
                app.TargetLOXmdotEditField_2.Value = 3.2;
                app.TargetFuelmdotEditField_2.Value = 1.61;

                app.EngineClassDropDown.Value = 'Vespula - HETS';

                app.LOXLeadEditField_4.Value = 0;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.NotesLabel.Text = 'Note: Fuel and LN2 are in separate files.';
            end
            if strcmp(value,'09.21.24 Subscale Coax Remachined')
                app.LOXInjectorCdAEditField.Value = 0.41;
                app.LOXSystemCdAEditField.Value = 1.004;
                app.FuelInjectorCdAEditField.Value = 0.32;
                app.FuelSystemCdAEditField.Value = 0.23;

                app.OTPTTargetEditField_2.Value = 362;
                app.FTPTTargetEditField_2.Value = 358;
                app.OIPTTargetEditField_2.Value = 353;
                app.FIPTTargetEditField_2.Value = 283;
                app.CHPTTargetEditField_2.Value = 260;
                app.TargetLOXmdotEditField_2.Value = 1.2;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 200;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'09.21.24 Fuel Flows')
                app.LOXInjectorCdAEditField.Value = 0;
                app.LOXSystemCdAEditField.Value = 0;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.23;

                app.OTPTTargetEditField_2.Value = 0;
                app.FTPTTargetEditField_2.Value = 356;
                app.OIPTTargetEditField_2.Value = 0;
                app.FIPTTargetEditField_2.Value = 315;
                app.CHPTTargetEditField_2.Value = 261;
                app.TargetLOXmdotEditField_2.Value = 1.2;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 0;
                app.OMVTimeEditField_2.Value = 0;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'04.28.24 Regen Ramp Up')
                app.LOXInjectorCdAEditField.Value = 0.313;
                app.LOXSystemCdAEditField.Value = 0.923;
                app.FuelInjectorCdAEditField.Value = 0.162;
                app.FuelSystemCdAEditField.Value = 0.234;

                app.OTPTTargetEditField_2.Value = 335;
                app.FTPTTargetEditField_2.Value = 400;
                app.OIPTTargetEditField_2.Value = 327;
                app.FIPTTargetEditField_2.Value = 312;
                app.CHPTTargetEditField_2.Value = 219;
                app.TargetLOXmdotEditField_2.Value = 1.19;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'04.28.24 Ablative Fire')
                app.LOXInjectorCdAEditField.Value = 0.313;
                app.LOXSystemCdAEditField.Value = 0.800;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.21;

                app.OTPTTargetEditField_2.Value = 360;
                app.FTPTTargetEditField_2.Value = 393;
                app.OIPTTargetEditField_2.Value = 356;
                app.FIPTTargetEditField_2.Value = 316;
                app.CHPTTargetEditField_2.Value = 261;
                app.TargetLOXmdotEditField_2.Value = 1.19;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'04.27.24 Pintle Ramp Up')
                app.LOXInjectorCdAEditField.Value = 0.3155;
                app.LOXSystemCdAEditField.Value = 0.2431;
                app.FuelInjectorCdAEditField.Value = 0.21;
                app.FuelSystemCdAEditField.Value = 0.2220;

                app.OTPTTargetEditField_2.Value = 326;
                app.FTPTTargetEditField_2.Value = 405;
                app.OIPTTargetEditField_2.Value = 320;
                app.FIPTTargetEditField_2.Value = 330;
                app.CHPTTargetEditField_2.Value = 261;
                app.TargetLOXmdotEditField_2.Value = 1.19;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Notes: ';
            end
            if strcmp(value,'04.20.24 Subscale Coax 5')
                app.LOXInjectorCdAEditField.Value = 0.319;
                app.LOXSystemCdAEditField.Value = 0.84;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.232;

                app.OTPTTargetEditField_2.Value = 325;
                app.FTPTTargetEditField_2.Value = 345;
                app.OIPTTargetEditField_2.Value = 240;
                app.FIPTTargetEditField_2.Value = 270;
                app.CHPTTargetEditField_2.Value = 200;
                app.TargetLOXmdotEditField_2.Value = 0.95;
                app.TargetFuelmdotEditField_2.Value = 0.53;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Note: Same as 04.20.24 Subscale Coax 4. Check mdots, Notion data seems wrong.';
            end
            if strcmp(value,'04.20.24 Subscale Coax 4')
                app.LOXInjectorCdAEditField.Value = 0.319;
                app.LOXSystemCdAEditField.Value = 0.84;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.232;

                app.OTPTTargetEditField_2.Value = 325;
                app.FTPTTargetEditField_2.Value = 345;
                app.OIPTTargetEditField_2.Value = 240;
                app.FIPTTargetEditField_2.Value = 270;
                app.CHPTTargetEditField_2.Value = 200;
                app.TargetLOXmdotEditField_2.Value = 0.95;
                app.TargetFuelmdotEditField_2.Value = 0.53;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Note: Same as 04.20.24 Subscale Coax 5.';
            end
            if strcmp(value,'04.13.24 Coax Ramp Up')
                app.LOXInjectorCdAEditField.Value = 0.319;
                app.LOXSystemCdAEditField.Value = 0.835;
                app.FuelInjectorCdAEditField.Value = 0.269;
                app.FuelSystemCdAEditField.Value = 0.215;

                app.OTPTTargetEditField_2.Value = 390;
                app.FTPTTargetEditField_2.Value = 370;
                app.OIPTTargetEditField_2.Value = 381;
                app.FIPTTargetEditField_2.Value = 316;
                app.CHPTTargetEditField_2.Value = 261;
                app.TargetLOXmdotEditField_2.Value = 1.19;
                app.TargetFuelmdotEditField_2.Value = 0.66;

                app.LOXLeadEditField_4.Value = 300;
                app.OMVTimeEditField_2.Value = 500;
                app.FMVTimeEditField_2.Value = 690;

                app.EngineClassDropDown.Value = 'Subscale - HETS';

                app.NotesLabel.Text = 'Note: Check tank pressures, Notion data seems wrong.';
            end
            if strcmp(value,'<no input selected>')
                app.LOXInjectorCdAEditField.Value = 0;
                app.LOXSystemCdAEditField.Value = 0;
                app.FuelInjectorCdAEditField.Value = 0;
                app.FuelSystemCdAEditField.Value = 0;

                app.OTPTTargetEditField_2.Value = 0;
                app.FTPTTargetEditField_2.Value = 0;
                app.OIPTTargetEditField_2.Value = 0;
                app.FIPTTargetEditField_2.Value = 0;
                app.CHPTTargetEditField_2.Value = 0;
                app.TargetLOXmdotEditField_2.Value = 0;
                app.TargetFuelmdotEditField_2.Value = 0;

                app.LOXLeadEditField_4.Value = 0;
                app.OMVTimeEditField_2.Value = 0;
                app.FMVTimeEditField_2.Value = 0;

                app.EngineClassDropDown.Value = '<no input selected>';

                app.NotesLabel.Text = 'Notes: ';
            end
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [app.file, app.filepath] = uigetfile({'*.csv';'*.mat'}, 'unga bunga', '..\HetsAnalysis\');
            app.datacsvEditField.Value = ['Loaded file: ' app.file];
        end

        % Button pushed function: FrankButton
        function FrankButtonPushed(app, event)
            frankfile = fullfile('NASA_CEA_Package', 'franknobg.png');
            frankim = imread(frankfile, BackgroundColor=[1 1 1]);
            image(frankim)
            annotation('textbox', [0.15, 0.2, 0.1, 0.1], 'String', 'I got thick thighs - Francis Frazier')
        end

        % Button pushed function: SaveOpenButton
        function SaveOpenButtonPushed(app, event)
            g = findobj('type', 'figure');
            h = findobj('type','axes');
            
            for i = 1:length(h)
                saveas(g(i), h(i).Title.String);
                saveas(g(i), h(i).Title.String, 'png');
            end
        end

        % Button pushed function: MakePowerPointButton
        function MakePowerPointButtonPushed(app, event)
            import mlreportgen.ppt.*;
            ppt = Presentation('sampleTDR.pptx');
            open(ppt);

            titleSlide = add(ppt,'Title Slide');
            today =  datestr(now, 'mm/dd/yyyy');
            replace(titleSlide,'Title', [today ' TDR']);
            replace(titleSlide,'Subtitle', 'Jackson Stahl');

            g = findobj('type', 'figure');
            h = findobj('type','axes');
            
            for i = 1:length(h)
                app.slidecounterEditField.Value = num2str(i);
                slide = add(ppt, 'Title and Picture');
                replace(slide, 'Title', h(i).Title.String)
                saveas(g(i), ['temp' num2str(i) '.png']);
                temp = Picture(['temp' num2str(i) '.png']);
                temp.Width='3in';
                temp.Height='40in';
                replace(slide, 'Picture', temp)
            end
            
            app.slidecounterEditField.Value = 'done!';

            close(ppt);
            rptview(ppt);

            app.slidecounterEditField.Value = 'done fr';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StahlDataAnalysisUIFigure = uifigure('Visible', 'off');
            app.StahlDataAnalysisUIFigure.Name = 'Stahl Data Analysis';
            app.TabGroup = uitabgroup(app.StahlDataAnalysisUIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.OMVTimeTextAreaLabel = uilabel(app.MainTab);
            app.OMVTimeTextAreaLabel.Text = 'OMV Time';
            app.OMVTimeTextArea = uitextarea(app.MainTab);
            app.OMVTimeTextArea.Value = {'--'};
            app.FMVTimeTextArea = uitextarea(app.MainTab);
            app.FMVTimeTextArea.Value = {'--'};
            app.LOXCheckBox = uicheckbox(app.MainTab);
            app.LOXCheckBox.ValueChangedFcn = createCallbackFcn(app, @LOXCheckBoxValueChanged, true);
            app.LOXCheckBox.Text = 'LOX';
            app.LOXCheckBox.Value = true;
            app.LN2CheckBox = uicheckbox(app.MainTab);
            app.LN2CheckBox.ValueChangedFcn = createCallbackFcn(app, @LN2CheckBoxValueChanged, true);
            app.LN2CheckBox.Text = 'LN2';
            app.JetACheckBox = uicheckbox(app.MainTab);
            app.JetACheckBox.ValueChangedFcn = createCallbackFcn(app, @JetACheckBoxValueChanged, true);
            app.JetACheckBox.Text = 'Jet-A';
            app.JetACheckBox.Value = true;
            app.WaterCheckBox = uicheckbox(app.MainTab);
            app.WaterCheckBox.ValueChangedFcn = createCallbackFcn(app, @WaterCheckBoxValueChanged, true);
            app.WaterCheckBox.Text = 'Water';
            app.datacsvEditField = uieditfield(app.MainTab, 'text');
            app.datacsvEditField.Value = '<load file here>';
            app.FMVTimeTextAreaLabel = uilabel(app.MainTab);
            app.FMVTimeTextAreaLabel.Text = 'FMV Time';
            app.datacsvEditFieldLabel = uilabel(app.MainTab);
            app.datacsvEditFieldLabel.Text = 'data file';
            app.RunButton = uibutton(app.MainTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TextArea = uitextarea(app.MainTab);
            app.TextArea.Value = {'Not running.'};
            app.PNPTTextArea = uitextarea(app.MainTab);
            app.PNPTTextArea.Value = {'--'};
            app.PNPTTextAreaLabel = uilabel(app.MainTab);
            app.PNPTTextAreaLabel.Text = 'PNPT';
            app.loxorfcdaTextAreaLabel = uilabel(app.MainTab);
            app.loxorfcdaTextAreaLabel.Text = 'LOX Orifice CdA';
            app.loxsyscdaTextAreaLabel = uilabel(app.MainTab);
            app.loxsyscdaTextAreaLabel.Text = 'LOX System CdA';
            app.venturicdTextAreaLabel = uilabel(app.MainTab);
            app.venturicdTextAreaLabel.Text = 'Venturi Cd';
            app.cm2Label = uilabel(app.MainTab);
            app.cm2Label.Text = 'cm2';
            app.cm2Label_2 = uilabel(app.MainTab);
            app.cm2Label_2.Text = 'cm2';
            app.Label = uilabel(app.MainTab);
            app.Label.Text = '--';
            app.fuelorfcdaTextAreaLabel = uilabel(app.MainTab);
            app.fuelorfcdaTextAreaLabel.Text = 'Fuel Orifice CdA';
            app.fuelsyscdaTextAreaLabel = uilabel(app.MainTab);
            app.fuelsyscdaTextAreaLabel.Text = 'Fuel System CdA';
            app.cm2Label_3 = uilabel(app.MainTab);
            app.cm2Label_3.Text = 'cm2';
            app.cm2Label_4 = uilabel(app.MainTab);
            app.cm2Label_4.Text = 'cm2';
            app.msLabel = uilabel(app.MainTab);
            app.msLabel.Text = 'ms';
            app.msLabel_2 = uilabel(app.MainTab);
            app.msLabel_2.Text = 'ms';
            app.psiLabel = uilabel(app.MainTab);
            app.psiLabel.Text = 'psi';
            app.loxorfcdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.loxsyscdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.venturicdTextArea = uieditfield(app.MainTab, 'numeric');
            app.venturicdTextArea.Value = 0.978;
            app.fuelorfcdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.fuelsyscdaTextArea = uieditfield(app.MainTab, 'numeric');
            app.omvflowdurTextArea = uitextarea(app.MainTab);
            app.omvflowdurTextArea.Value = {'--'};
            app.omvflowdurTextAreaLabel = uilabel(app.MainTab);
            app.omvflowdurTextAreaLabel.Text = 'OMV Flow Duration';
            app.sLabel_2 = uilabel(app.MainTab);
            app.sLabel_2.Text = 's';
            app.fmvflowdurTextArea = uitextarea(app.MainTab);
            app.fmvflowdurTextArea.Value = {'--'};
            app.fmvflowdurTextAreaLabel = uilabel(app.MainTab);
            app.fmvflowdurTextAreaLabel.Text = 'FMV Flow Duration';
            app.sLabel_3 = uilabel(app.MainTab);
            app.sLabel_3.Text = 's';
            app.SamplingRateTextArea = uitextarea(app.MainTab);
            app.SamplingRateTextArea.Value = {'--'};
            app.SamplingRateTextAreaLabel = uilabel(app.MainTab);
            app.SamplingRateTextAreaLabel.Text = 'Sampling Rate';
            app.HzLabel = uilabel(app.MainTab);
            app.HzLabel.Text = 'Hz';
            app.TEditFieldLabel = uilabel(app.MainTab);
            app.TEditFieldLabel.Text = 'T-';
            app.TEditField = uieditfield(app.MainTab, 'numeric');
            app.TEditField.Value = 2;
            app.TLabel = uilabel(app.MainTab);
            app.TLabel.Text = 'T+';
            app.TEditField_2 = uieditfield(app.MainTab, 'numeric');
            app.TEditField_2.Value = 8;
            app.OrificeandinjectorusedinterchangablyinoutputsLabel = uilabel(app.MainTab);
            app.OrificeandinjectorusedinterchangablyinoutputsLabel.Text = '*Orifice and injector used interchangably in outputs';
            app.madebyJacksonStahlLabel = uilabel(app.MainTab);
            app.madebyJacksonStahlLabel.Text = 'made by Jackson Stahl';
            app.InputsLabel = uilabel(app.MainTab);
            app.InputsLabel.Text = 'Inputs:';
            app.versionLabel = uilabel(app.MainTab);
            app.versionLabel.Text = 'v0.5.2';
            app.LOXLeadLabel = uilabel(app.MainTab);
            app.LOXLeadLabel.Text = 'LOX Lead';
            app.LOXLeadEditField = uieditfield(app.MainTab, 'numeric');
            app.LOXLeadEditField.Value = 100;
            app.OMVTimeEditFieldLabel = uilabel(app.MainTab);
            app.OMVTimeEditFieldLabel.Text = 'OMV Time';
            app.OMVTimeEditField = uieditfield(app.MainTab, 'numeric');
            app.OMVTimeEditField.Value = 130;
            app.FMVTimeEditFieldLabel = uilabel(app.MainTab);
            app.FMVTimeEditFieldLabel.Text = 'FMV Time';
            app.FMVTimeEditField = uieditfield(app.MainTab, 'numeric');
            app.FMVTimeEditField.Value = 120;
            app.msLabel_3 = uilabel(app.MainTab);
            app.msLabel_3.Text = 'ms';
            app.LOXLeadEditField_3Label = uilabel(app.MainTab);
            app.LOXLeadEditField_3Label.Text = 'LOX Lead';
            app.LOXLeadEditField_3 = uieditfield(app.MainTab, 'text');
            app.LOXLeadEditField_3.Value = '--';
            app.OutputsLabel = uilabel(app.MainTab);
            app.OutputsLabel.Text = 'Outputs:';
            app.fmmultiplierTextAreaLabel = uilabel(app.MainTab);
            app.fmmultiplierTextAreaLabel.Text = 'FM Correction Factor';
            app.Label_2 = uilabel(app.MainTab);
            app.Label_2.Text = '--';
            app.fmmultiplierTextArea = uieditfield(app.MainTab, 'numeric');
            app.fmmultiplierTextArea.Value = 1;
            app.LOXLN2densityEditFieldLabel = uilabel(app.MainTab);
            app.LOXLN2densityEditFieldLabel.Text = 'LOX/LN2 density';
            app.LOXLN2densityEditField = uieditfield(app.MainTab, 'numeric');
            app.LOXLN2densityEditField.Value = 1077;
            app.kgm3Label = uilabel(app.MainTab);
            app.kgm3Label.Text = 'kg/m3';
            app.OTPTTargetEditFieldLabel = uilabel(app.MainTab);
            app.OTPTTargetEditFieldLabel.Text = 'OTPT Target';
            app.OTPTTargetEditField = uieditfield(app.MainTab, 'numeric');
            app.FTPTTargetEditFieldLabel = uilabel(app.MainTab);
            app.FTPTTargetEditFieldLabel.Text = 'FTPT Target';
            app.FTPTTargetEditField = uieditfield(app.MainTab, 'numeric');
            app.OIPTTargetEditFieldLabel = uilabel(app.MainTab);
            app.OIPTTargetEditFieldLabel.Text = 'OIPT Target';
            app.OIPTTargetEditField = uieditfield(app.MainTab, 'numeric');
            app.FIPTTargetEditFieldLabel = uilabel(app.MainTab);
            app.FIPTTargetEditFieldLabel.Text = 'FIPT Target';
            app.FIPTTargetEditField = uieditfield(app.MainTab, 'numeric');
            app.CHPTTargetEditFieldLabel = uilabel(app.MainTab);
            app.CHPTTargetEditFieldLabel.Text = 'CHPT Target';
            app.CHPTTargetEditField = uieditfield(app.MainTab, 'numeric');
            app.TargetLOXmdotEditFieldLabel = uilabel(app.MainTab);
            app.TargetLOXmdotEditFieldLabel.Text = 'Target LOX mdot';
            app.TargetLOXmdotEditField = uieditfield(app.MainTab, 'numeric');
            app.TargetLOXmdotEditField.Value = 3.2;
            app.TargetFuelmdotEditFieldLabel = uilabel(app.MainTab);
            app.TargetFuelmdotEditFieldLabel.Text = 'Target Fuel mdot';
            app.TargetFuelmdotEditField = uieditfield(app.MainTab, 'numeric');
            app.TargetFuelmdotEditField.Value = 1.61;
            app.CalculateCheckBox_2 = uicheckbox(app.MainTab);
            app.CalculateCheckBox_2.Text = 'Calculate';
            app.CalculateCheckBox_2.Value = true;
            app.CalculateCheckBox = uicheckbox(app.MainTab);
            app.CalculateCheckBox.Text = 'Calculate';
            app.CalculateCheckBox.Value = true;
            app.DatasetInputLabel = uilabel(app.MainTab);
            app.DatasetInputLabel.Text = '<no input selected>';
            app.LoadFileButton = uibutton(app.MainTab, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.CheckBox = uicheckbox(app.MainTab);
            app.CheckBox.Text = '';
            app.CheckBox.Value = true;
            app.CollapseFactorBetaEditFieldLabel = uilabel(app.MainTab);
            app.CollapseFactorBetaEditFieldLabel.Text = 'Collapse Factor (Beta)';
            app.CollapseFactorBetaEditField = uieditfield(app.MainTab, 'numeric');
            app.SpecialboxCheckBox = uicheckbox(app.MainTab);
            app.SpecialboxCheckBox.Text = 'Special box';
            app.EngineClassDropDown = uidropdown(app.MainTab);
            app.EngineClassDropDown.Items = {'Subscale - HETS', 'Vespula - HETS', 'Vespula - Rocket'};
            app.EngineClassDropDown.Value = 'Subscale - HETS';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.PlotAllButton = uibutton(app.PlotsTab, 'push');
            app.PlotAllButton.ButtonPushedFcn = createCallbackFcn(app, @PlotAllButtonPushed, true);
            app.PlotAllButton.Text = 'Plot All';
            app.PurgeButton = uibutton(app.PlotsTab, 'push');
            app.PurgeButton.ButtonPushedFcn = createCallbackFcn(app, @PurgeButtonPushed, true);
            app.PurgeButton.Text = 'Purge';
            app.PressureLaddersButton = uibutton(app.PlotsTab, 'push');
            app.PressureLaddersButton.ButtonPushedFcn = createCallbackFcn(app, @PressureLaddersButtonPushed, true);
            app.PressureLaddersButton.Text = 'Pressure Ladders';
            app.mdotsButton = uibutton(app.PlotsTab, 'push');
            app.mdotsButton.ButtonPushedFcn = createCallbackFcn(app, @mdotsButtonPushed, true);
            app.mdotsButton.Text = 'mdots';
            app.CdAsButton = uibutton(app.PlotsTab, 'push');
            app.CdAsButton.ButtonPushedFcn = createCallbackFcn(app, @CdAsButtonPushed, true);
            app.CdAsButton.Text = 'CdAs';
            app.ValvesButton = uibutton(app.PlotsTab, 'push');
            app.ValvesButton.ButtonPushedFcn = createCallbackFcn(app, @ValvesButtonPushed, true);
            app.ValvesButton.Text = 'Valves';
            app.ThrustButton = uibutton(app.PlotsTab, 'push');
            app.ThrustButton.ButtonPushedFcn = createCallbackFcn(app, @ThrustButtonPushed, true);
            app.ThrustButton.Text = 'Thrust';
            app.PRPT1CheckBox = uicheckbox(app.PlotsTab);
            app.PRPT1CheckBox.Text = 'PRPT1';
            app.PRPT2CheckBox = uicheckbox(app.PlotsTab);
            app.PRPT2CheckBox.Text = 'PRPT2';
            app.OTPTCheckBox = uicheckbox(app.PlotsTab);
            app.OTPTCheckBox.Text = 'OTPT';
            app.OTPTCheckBox.Value = true;
            app.ORUNPTCheckBox = uicheckbox(app.PlotsTab);
            app.ORUNPTCheckBox.Text = 'ORUNPT';
            app.ORUNPTCheckBox.Value = true;
            app.OIPTCheckBox = uicheckbox(app.PlotsTab);
            app.OIPTCheckBox.Text = 'OIPT';
            app.OIPTCheckBox.Value = true;
            app.FTPTCheckBox = uicheckbox(app.PlotsTab);
            app.FTPTCheckBox.Text = 'FTPT';
            app.FTPTCheckBox.Value = true;
            app.FRUNPTCheckBox = uicheckbox(app.PlotsTab);
            app.FRUNPTCheckBox.Text = 'FRUNPT';
            app.FRUNPTCheckBox.Value = true;
            app.FIPTCheckBox = uicheckbox(app.PlotsTab);
            app.FIPTCheckBox.Text = 'FIPT';
            app.FIPTCheckBox.Value = true;
            app.CHPTCheckBox = uicheckbox(app.PlotsTab);
            app.CHPTCheckBox.Text = 'CHPT';
            app.CHPTCheckBox.Value = true;
            app.FLPTCheckBox = uicheckbox(app.PlotsTab);
            app.FLPTCheckBox.Text = 'FLPT';
            app.FINPTCheckBox = uicheckbox(app.PlotsTab);
            app.FINPTCheckBox.Text = 'FINPT';
            app.ShowinputsCheckBox_2 = uicheckbox(app.PlotsTab);
            app.ShowinputsCheckBox_2.Text = 'Show inputs';
            app.CloseAllButton = uibutton(app.PlotsTab, 'push');
            app.CloseAllButton.ButtonPushedFcn = createCallbackFcn(app, @CloseAllButtonPushed, true);
            app.CloseAllButton.Text = 'Close All';
            app.EngineDataButton = uibutton(app.PlotsTab, 'push');
            app.EngineDataButton.ButtonPushedFcn = createCallbackFcn(app, @EngineDataButtonPushed, true);
            app.EngineDataButton.Text = 'Engine Data';
            app.avgloxmdotorfTextArea = uitextarea(app.PlotsTab);
            app.avgloxmdotorfTextArea.Value = {'--'};
            app.avgloxmdotorfTextAreaLabel = uilabel(app.PlotsTab);
            app.avgloxmdotorfTextAreaLabel.Text = 'Avg LOX Mdot (Orf CdA)';
            app.kgsLabel = uilabel(app.PlotsTab);
            app.kgsLabel.Text = 'kg/s';
            app.avgloxmdotsysTextArea = uitextarea(app.PlotsTab);
            app.avgloxmdotsysTextArea.Value = {'--'};
            app.avgloxmdotsysTextAreaLabel = uilabel(app.PlotsTab);
            app.avgloxmdotsysTextAreaLabel.Text = 'Avg LOX Mdot (Sys CdA)';
            app.kgsLabel_2 = uilabel(app.PlotsTab);
            app.kgsLabel_2.Text = 'kg/s';
            app.avgloxmdotventuriTextArea = uitextarea(app.PlotsTab);
            app.avgloxmdotventuriTextArea.Value = {'--'};
            app.avgloxmdotventuriTextAreaLabel = uilabel(app.PlotsTab);
            app.avgloxmdotventuriTextAreaLabel.Text = 'Avg LOX Mdot (Venturi)';
            app.kgsLabel_3 = uilabel(app.PlotsTab);
            app.kgsLabel_3.Text = 'kg/s';
            app.avgfuelmdotorfTextArea = uitextarea(app.PlotsTab);
            app.avgfuelmdotorfTextArea.Value = {'--'};
            app.avgfuelmdotorfTextAreaLabel = uilabel(app.PlotsTab);
            app.avgfuelmdotorfTextAreaLabel.Text = 'Avg Fuel Mdot (Orf CdA)';
            app.kgsLabel_4 = uilabel(app.PlotsTab);
            app.kgsLabel_4.Text = 'kg/s';
            app.avgfuelmdotsysTextArea = uitextarea(app.PlotsTab);
            app.avgfuelmdotsysTextArea.Value = {'--'};
            app.avgfuelmdotsysTextAreaLabel = uilabel(app.PlotsTab);
            app.avgfuelmdotsysTextAreaLabel.Text = 'Avg Fuel Mdot (Sys CdA)';
            app.kgsLabel_5 = uilabel(app.PlotsTab);
            app.kgsLabel_5.Text = 'kg/s';
            app.avgfuelmdotfmTextArea = uitextarea(app.PlotsTab);
            app.avgfuelmdotfmTextArea.Value = {'--'};
            app.avgfuelmdotfmTextAreaLabel = uilabel(app.PlotsTab);
            app.avgfuelmdotfmTextAreaLabel.Text = 'Avg Fuel Mdot (Flow Meter)';
            app.kgsLabel_6 = uilabel(app.PlotsTab);
            app.kgsLabel_6.Text = 'kg/s';
            app.cm2Label_5 = uilabel(app.PlotsTab);
            app.cm2Label_5.Text = 'cm2';
            app.cm2Label_6 = uilabel(app.PlotsTab);
            app.cm2Label_6.Text = 'cm2';
            app.cm2Label_7 = uilabel(app.PlotsTab);
            app.cm2Label_7.Text = 'cm2';
            app.cm2Label_8 = uilabel(app.PlotsTab);
            app.cm2Label_8.Text = 'cm2';
            app.cm2Label_9 = uilabel(app.PlotsTab);
            app.cm2Label_9.Text = 'cm2';
            app.cm2Label_10 = uilabel(app.PlotsTab);
            app.cm2Label_10.Text = 'cm2';
            app.cm2Label_11 = uilabel(app.PlotsTab);
            app.cm2Label_11.Text = 'cm2';
            app.cm2Label_12 = uilabel(app.PlotsTab);
            app.cm2Label_12.Text = 'cm2';
            app.AvgLOXSysCdAOrificeEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgLOXSysCdAOrificeEditFieldLabel.Text = 'Avg LOX Sys CdA (Orifice)';
            app.AvgLOXSysCdAOrificeEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgLOXSysCdAOrificeEditField.Value = '--';
            app.AvgLOXSysCdAVenturiEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgLOXSysCdAVenturiEditFieldLabel.Text = 'Avg LOX Sys CdA (Venturi)';
            app.AvgLOXSysCdAVenturiEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgLOXSysCdAVenturiEditField.Value = '--';
            app.AvgLOXOrfCdASysCdAEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgLOXOrfCdASysCdAEditFieldLabel.Text = 'Avg LOX Orf CdA (Sys CdA)';
            app.AvgLOXOrfCdASysCdAEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgLOXOrfCdASysCdAEditField.Value = '--';
            app.AvgLOXOrfCdAVenturiEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgLOXOrfCdAVenturiEditFieldLabel.Text = 'Avg LOX Orf CdA (Venturi)';
            app.AvgLOXOrfCdAVenturiEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgLOXOrfCdAVenturiEditField.Value = '--';
            app.AvgFuelSysCdAOrificeEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgFuelSysCdAOrificeEditFieldLabel.Text = 'Avg Fuel Sys CdA (Orifice)';
            app.AvgFuelSysCdAOrificeEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgFuelSysCdAOrificeEditField.Value = '--';
            app.AvgFuelOrfCdASysCdAEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgFuelOrfCdASysCdAEditFieldLabel.Text = 'Avg Fuel Orf CdA (Sys CdA)';
            app.AvgFuelOrfCdASysCdAEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgFuelOrfCdASysCdAEditField.Value = '--';
            app.AvgFuelSysCdAFlowMeterEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgFuelSysCdAFlowMeterEditFieldLabel.Text = 'Avg Fuel Sys CdA (Flow Meter)';
            app.AvgFuelSysCdAFlowMeterEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgFuelSysCdAFlowMeterEditField.Value = '--';
            app.AvgFuelOrfCdAFlowMeterEditFieldLabel = uilabel(app.PlotsTab);
            app.AvgFuelOrfCdAFlowMeterEditFieldLabel.Text = 'Avg Fuel Orf CdA (Flow Meter)';
            app.AvgFuelOrfCdAFlowMeterEditField = uieditfield(app.PlotsTab, 'text');
            app.AvgFuelOrfCdAFlowMeterEditField.Value = '--';
            app.ShowinputsCheckBox = uicheckbox(app.PlotsTab);
            app.ShowinputsCheckBox.Text = 'Show inputs';
            app.TemperaturesButton = uibutton(app.PlotsTab, 'push');
            app.TemperaturesButton.ButtonPushedFcn = createCallbackFcn(app, @TemperaturesButtonPushed, true);
            app.TemperaturesButton.Text = 'Temperatures';
            app.PNPTCheckBox = uicheckbox(app.PlotsTab);
            app.PNPTCheckBox.Text = 'PNPT';
            app.PNPTCheckBox.Value = true;
            app.FLOWCheckBox = uicheckbox(app.PlotsTab);
            app.FLOWCheckBox.Text = 'FLOW';
            app.ODPCheckBox = uicheckbox(app.PlotsTab);
            app.ODPCheckBox.Text = 'ODP';
            app.WithPressuresCheckBox = uicheckbox(app.PlotsTab);
            app.WithPressuresCheckBox.Text = 'With Pressures';
            app.ShowdatatipsCheckBox = uicheckbox(app.PlotsTab);
            app.ShowdatatipsCheckBox.Text = 'Show datatips';
            app.ShowdatatipsCheckBox_2 = uicheckbox(app.PlotsTab);
            app.ShowdatatipsCheckBox_2.Text = 'Show datatips';
            app.PRPTOCheckBox = uicheckbox(app.PlotsTab);
            app.PRPTOCheckBox.Text = 'PRPTO';
            app.PRPTFCheckBox = uicheckbox(app.PlotsTab);
            app.PRPTFCheckBox.Text = 'PRPTF';
            app.SaveOpenButton = uibutton(app.PlotsTab, 'push');
            app.SaveOpenButton.ButtonPushedFcn = createCallbackFcn(app, @SaveOpenButtonPushed, true);
            app.SaveOpenButton.Text = 'Save Open';
            app.CEfficiencyButton = uibutton(app.PlotsTab, 'push');
            app.CEfficiencyButton.ButtonPushedFcn = createCallbackFcn(app, @CEfficiencyButtonPushed, true);
            app.CEfficiencyButton.Text = 'C* Efficiency';
            app.LoadInputsTab = uitab(app.TabGroup);
            app.LoadInputsTab.Title = 'Load Inputs';
            app.LOXInjectorCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.LOXInjectorCdAEditFieldLabel.Text = 'LOX Injector CdA';
            app.LOXInjectorCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.LOXSystemCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.LOXSystemCdAEditFieldLabel.Text = 'LOX System CdA';
            app.LOXSystemCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.FuelInjectorCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.FuelInjectorCdAEditFieldLabel.Text = 'Fuel Injector CdA';
            app.FuelInjectorCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.FuelSystemCdAEditFieldLabel = uilabel(app.LoadInputsTab);
            app.FuelSystemCdAEditFieldLabel.Text = 'Fuel System CdA';
            app.FuelSystemCdAEditField = uieditfield(app.LoadInputsTab, 'numeric');
            app.LoadButton = uibutton(app.LoadInputsTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DatasetLabel = uilabel(app.LoadInputsTab);
            app.DatasetLabel.Text = 'Dataset:';
            app.DatasetDropDown = uidropdown(app.LoadInputsTab);
            app.DatasetDropDown.Items = {'<no input selected>', '02.22.25 SS HS Heat Flux 2', '02.22.25 Vespula Heatsink 3.5', '02.01.25 Vespula Heatscrub', '11.24.24 Subscale Heatsink Heat Flux', '11.16.24 Vespula Main Valves Fire', '11.16.24 Vespula Flows New Sequence', '10.19.24 Subscale Ablative V2.5', '10.19.24 Vespula Heatsink', '10.06.24 Subscale Ablative', '10.05.24 Vespula Flows', '09.28.24 Vespula Flows', '09.21.24 Subscale Coax Remachined', '09.21.24 Fuel Flows', '04.28.24 Regen Ramp Up', '04.28.24 Ablative Fire', '04.27.24 Pintle Ramp Up', '04.20.24 Subscale Coax 5', '04.20.24 Subscale Coax 4', '04.13.24 Coax Ramp Up'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = '<no input selected>';
            app.OTPTTargetEditField_2Label = uilabel(app.LoadInputsTab);
            app.OTPTTargetEditField_2Label.Text = 'OTPT Target';
            app.OTPTTargetEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.FTPTTargetEditField_2Label = uilabel(app.LoadInputsTab);
            app.FTPTTargetEditField_2Label.Text = 'FTPT Target';
            app.FTPTTargetEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.OIPTTargetEditField_2Label = uilabel(app.LoadInputsTab);
            app.OIPTTargetEditField_2Label.Text = 'OIPT Target';
            app.OIPTTargetEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.FIPTTargetEditField_2Label = uilabel(app.LoadInputsTab);
            app.FIPTTargetEditField_2Label.Text = 'FIPT Target';
            app.FIPTTargetEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.CHPTTargetEditField_2Label = uilabel(app.LoadInputsTab);
            app.CHPTTargetEditField_2Label.Text = 'CHPT Target';
            app.CHPTTargetEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.TargetLOXmdotEditField_2Label = uilabel(app.LoadInputsTab);
            app.TargetLOXmdotEditField_2Label.Text = 'Target LOX mdot';
            app.TargetLOXmdotEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.TargetFuelmdotEditField_2Label = uilabel(app.LoadInputsTab);
            app.TargetFuelmdotEditField_2Label.Text = 'Target Fuel mdot';
            app.TargetFuelmdotEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.NotesLabel = uilabel(app.LoadInputsTab);
            app.NotesLabel.Text = 'Notes: ';
            app.BepatientLabel = uilabel(app.LoadInputsTab);
            app.BepatientLabel.Text = 'Be patient.';
            app.SwitchORUNandFRUNCheckBox = uicheckbox(app.LoadInputsTab);
            app.SwitchORUNandFRUNCheckBox.Text = 'Switch ORUN and FRUN';
            app.LOXLeadEditField_4Label = uilabel(app.LoadInputsTab);
            app.LOXLeadEditField_4Label.Text = 'LOX Lead';
            app.LOXLeadEditField_4 = uieditfield(app.LoadInputsTab, 'numeric');
            app.OMVTimeEditField_2Label = uilabel(app.LoadInputsTab);
            app.OMVTimeEditField_2Label.Text = 'OMV Time';
            app.OMVTimeEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.FMVTimeEditField_2Label = uilabel(app.LoadInputsTab);
            app.FMVTimeEditField_2Label.Text = 'FMV Time';
            app.FMVTimeEditField_2 = uieditfield(app.LoadInputsTab, 'numeric');
            app.PowerPointBetaTab = uitab(app.TabGroup);
            app.PowerPointBetaTab.Title = 'PowerPoint (Beta)';
            app.MakePowerPointButton = uibutton(app.PowerPointBetaTab, 'push');
            app.MakePowerPointButton.ButtonPushedFcn = createCallbackFcn(app, @MakePowerPointButtonPushed, true);
            app.MakePowerPointButton.Text = 'Make PowerPoint';
            app.TextArea_3 = uitextarea(app.PowerPointBetaTab);
            app.TextArea_3.Value = {'This will generate a PowerPoint from all of the open plots. It is very much in beta. To use it, you must install the MATLAB Report Generator Add-On from the Add-Ons menu in the HOME tab of MATLAB.'};
            app.slidecounterEditFieldLabel = uilabel(app.PowerPointBetaTab);
            app.slidecounterEditFieldLabel.Text = 'slide counter';
            app.slidecounterEditField = uieditfield(app.PowerPointBetaTab, 'text');
            app.ChangelogTab = uitab(app.TabGroup);
            app.ChangelogTab.Title = 'Changelog';
            app.TextArea_2 = uitextarea(app.ChangelogTab);
            app.TextArea_2.Value = {'v0.5.2'; 'Added 02.22.25 input datasets'; 'Began debugging collapse factor calculations - NOT WORKING'; 'Added file generation scheme'; 'Introduced Engine Class dropdown'; 'Began integrating Vespula data analysis'; ''; 'v0.5.1'; 'Renamed 02.01.25 fire'; 'Added collapse factor calculations'; 'Code commented'; 'Moved plot functions to underneath their callbacks'; ''; 'v0.5'; 'Add ISP calcs under Thrust'; 'Add C* Efficiency'; 'Switches directory on run'; 'Frank button added'; ''; 'v0.4.1.1'; 'Fixed dataset input for 09.21.24 Subscale Coax Remachined - system cdas were swapped'; ''; 'v0.4.1'; 'Added PRPTO and PRPTF'};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
            app.FrankButton = uibutton(app.ChangelogTab, 'push');
            app.FrankButton.ButtonPushedFcn = createCallbackFcn(app, @FrankButtonPushed, true);
            app.FrankButton.Text = 'Frank';
            % Show the figure after all components are created
            app.StahlDataAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StahlDataAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StahlDataAnalysisUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StahlDataAnalysisUIFigure)
        end
    end
end